classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Button_crop_Img      matlab.ui.control.Button
        nhchnLabel           matlab.ui.control.Label
        Label_image_name     matlab.ui.control.Label
        EditField_file_path  matlab.ui.control.EditField
        FileEditFieldLabel   matlab.ui.control.Label
        Button_add_Img       matlab.ui.control.Button
        CenterPanel          matlab.ui.container.Panel
        UIAxes_crop_Img      matlab.ui.control.UIAxes
        UIAxes_display_Img   matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        Button               matlab.ui.control.Button
        ExitButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CroppedImage   % ảnh sau khi cắt
        grayImg % ảnh gray scale
    end
    
    methods (Access = private)
        
        function grayImg = convertToGray(app, Img_Original)
        if size(Img_Original, 3) == 3
            grayImg = rgb2gray(Img_Original);
        else
            grayImg = Img_Original;
        end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_add_Img
        function Button_add_ImgPushed(app, event)
                     
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Ảnh (*.jpg, *.png, *.bmp)'}, ...
                                     'Chọn ảnh');
            if isequal(file, 0)
                return;
            end
        
            % Đưa app lên trước sau khi chọn xong
            figure(app.UIFigure);  % hoặc movegui(app.UIFigure)
        
            % Lưu đường dẫn
            fullPath = fullfile(path, file);
            app.EditField_file_path.Value = fullPath;
            app.Label_image_name.Text = file;
        
            % Đọc ảnh gốc
            img_original = imread(fullPath);
        
            % Chuyển ảnh sang grayscale và kiểu double
            app.grayImg = app.convertToGray(img_original);
        
            % Hiển thị ảnh sau khi đã xử lý
            imshow(app.grayImg, 'Parent', app.UIAxes_display_Img);


        end

        % Button pushed function: Button_crop_Img
        function Button_crop_ImgPushed(app, event)

                    % Kiểm tra có ảnh đầu vào hay không
            if isempty(app.grayImg)
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào. Vui lòng chọn ảnh trước.', 'Lỗi');
                return;
            end
        
            % Mở cửa sổ mới để người dùng chọn vùng cần cắt
            f = figure('Name', 'Chọn vùng cắt', 'NumberTitle', 'off');
            imshow(app.grayImg);
            title('Chọn vùng để cắt, sau đó nhấn Enter');
        
            % Chọn vùng cắt
            rect = getrect(f);  % Tự động chờ người dùng chọn vùng rồi nhấn Enter
            close(f);  % Đóng cửa sổ chọn vùng
        
            % Cắt ảnh và hiển thị kết quả
            croppedImg = imcrop(app.grayImg, rect);
            imshow(croppedImg, 'Parent', app.UIAxes_crop_Img);
        
            % Lưu lại ảnh đã cắt vào thuộc tính của app
            app.CroppedImage = croppedImg;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Hiện hộp thoại xác nhận
            choice = uiconfirm(app.UIFigure, ...
                               'Bạn có chắc chắn muốn thoát?', ...
                               'Thoát ứng dụng', ...
                               'Options', {'Thoát','Huỷ'}, ...
                               'DefaultOption', 2, ...
                               'CancelOption', 2);
            if strcmp(choice, 'Thoát')
                delete(app);  % Đóng ứng dụng
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {522, 522, 522};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {522, 522};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {276, '1x', 201};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button_add_Img = uibutton(app.LeftPanel, 'push');
            app.Button_add_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_add_ImgPushed, true);
            app.Button_add_Img.Text = 'Chọn ảnh';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.EditField_file_path = uieditfield(app.LeftPanel, 'text');
            app.Label_image_name = uilabel(app.LeftPanel);
            app.Label_image_name.Text = 'Tên ảnh';
            app.nhchnLabel = uilabel(app.LeftPanel);
            app.nhchnLabel.Text = 'Ảnh đã chọn:';
            app.Button_crop_Img = uibutton(app.LeftPanel, 'push');
            app.Button_crop_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_crop_ImgPushed, true);
            app.Button_crop_Img.Text = 'Crop ảnh';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes_display_Img = uiaxes(app.CenterPanel);
            title(app.UIAxes_display_Img, 'Ảnh ban đầu (gray scale)')
            zlabel(app.UIAxes_display_Img, 'Z')
            app.UIAxes_display_Img.XTick = [];
            app.UIAxes_display_Img.YTick = [];
            app.UIAxes_crop_Img = uiaxes(app.CenterPanel);
            title(app.UIAxes_crop_Img, 'Ảnh cropped')
            zlabel(app.UIAxes_crop_Img, 'Z')
            app.UIAxes_crop_Img.XTick = [];
            app.UIAxes_crop_Img.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ExitButton = uibutton(app.RightPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Button = uibutton(app.RightPanel, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end