classdef app1_fringe_detection_backup2_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Button_Enhance               matlab.ui.control.Button
        Label_func_constrast         matlab.ui.control.Label
        DropDown_ContrastEnhance     matlab.ui.control.DropDown
        TngphnLabel                  matlab.ui.control.Label
        Label_func_nen               matlab.ui.control.Label
        DropDown_BackgroundNorm      matlab.ui.control.DropDown
        ChunhannLabel                matlab.ui.control.Label
        Label_func_filter            matlab.ui.control.Label
        DropDown_FilterMethod        matlab.ui.control.DropDown
        LcnhiuLabel                  matlab.ui.control.Label
        Button_crop_Img              matlab.ui.control.Button
        Label_image_name             matlab.ui.control.Label
        nhchnLabel                   matlab.ui.control.Label
        Button_add_Img               matlab.ui.control.Button
        EditField_file_path          matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        Sourcegithubcomtuanpd205459Label  matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Button_show_skeletonization  matlab.ui.control.Button
        Button_show_enhance          matlab.ui.control.Button
        Button_show_cropped          matlab.ui.control.Button
        Button_show_img_orig         matlab.ui.control.Button
        UIAxes_Skeleton              matlab.ui.control.UIAxes
        UIAxes_enhance_Img           matlab.ui.control.UIAxes
        UIAxes_crop_Img              matlab.ui.control.UIAxes
        UIAxes_display_Img           matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DropDown_reconstruc_surface  matlab.ui.control.DropDown
        DropDown_remove_tilt         matlab.ui.control.DropDown
        Button_remove_tilt           matlab.ui.control.Button
        Button_reconstruct_surface   matlab.ui.control.Button
        Button_fringe_order          matlab.ui.control.Button
        DropDown_manual_remove       matlab.ui.control.DropDown
        Button_manual_link           matlab.ui.control.Button
        Button_manual_remove         matlab.ui.control.Button
        Button_RefineSkeleton_iter   matlab.ui.control.Button
        Button_RefineSkeleton        matlab.ui.control.Button
        Button_skeletonize           matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        CroppedImage    % ảnh sau khi cắt
        grayImg         % ảnh gray scale
        ProcessedImage  % ảnh sau xử lý nâng cao
        Skeleton % ảnh xương
        Skeleton_refined % ảnh xương sau tinh chỉnh
        figure_refine = 6; % số thứ tự figure
        figure_manual_remove = 7; %số thứ tự ảnh manual remove
        figure_manual_link = 8 % số thứ tự ảnh manual link
        figure_fringe_order = 9; % STT ảnh fringe order
        fiugre_reconstruct_surface = 10; % STT anh tai tao
        figure_remove_tilt = 11; %STT anh remove tilt

        temp_var % biến lưu giữ giá trị trung gian qua các giai đoạn xử lý


        fringe_order % bậc vân
        recons_surface % bề mặt được tái tạo.

        fringe_labels % labels vân


        lambda = 632.8*1e-9 % bước sóng
    end

    methods (Access = private)

        function grayImg = convertToGray(~, Img_Original)
            if size(Img_Original, 3) == 3
                grayImg = rgb2gray(Img_Original);
            else
                grayImg = Img_Original;
            end
        end


        function anh_sach = loai_bo_nhieu(~, anh_nhi_phan)
            % LOAI_BO_NHIEU  Loại bỏ các điểm ảnh nhiễu đơn lẻ hoặc thò ra khỏi đường chính
            % Đầu vào:
            %   - anh_nhi_phan: ảnh nhị phân (0 và 1)
            % Đầu ra:
            %   - anh_sach: ảnh sau khi đã loại bỏ nhiễu

            % Kiểm tra đầu vào là ảnh nhị phân
            if ~islogical(anh_nhi_phan)
                anh_nhi_phan = imbinarize(anh_nhi_phan);
            end

            % Tạo phần tử cấu trúc 3x3
            se = strel('square', 3);

            % Đóng để nối các trace bị đứt
            anh_dong = imclose(anh_nhi_phan, se);

            % Mở để loại bỏ các vùng nhỏ và nhiễu
            anh_mo = imopen(anh_dong, se);

            % Xử lý thêm bằng bwmorph
            anh_clean = bwmorph(anh_mo, 'clean');  % Xoá điểm đơn lẻ
            anh_clean = bwmorph(anh_clean, 'spur');  % Xoá điểm thò ra

            % Kết quả cuối
            anh_sach = anh_clean;

        end


        %% func tinh chỉnh vân
        function refined = refineSkeleton(~, binaryImg)
            % Đầu vào là ảnh nhị phân skeleton (binary)

            % Loại bỏ nhiễu nhỏ (râu ria)
            cleaned = bwmorph(binaryImg, 'spur', 5);  % loại bỏ các nhánh nhỏ

            % Dùng morphological closing để nối các đoạn đứt
            se = strel('disk', 2);  % kernel nhỏ để không nối bừa bãi
            closed = imclose(cleaned, se);

            % Lấy lại skeleton từ ảnh đã nối
            skeleton = bwmorph(closed, 'skel', Inf);

            % Tùy chọn loại bỏ tiếp râu ria sau khi nối
            refined = bwmorph(skeleton, 'spur', 5);

            % Xoá điểm trắng đứng 1 mình
            refined = bwareaopen(refined, 2);  % xóa các vùng có diện tích < 2 pixel
        end

        %%

        function updateButtonVisible(app)

            if isempty(app.Skeleton_refined) % nút lặp tinh chỉnh xương
                app.Button_RefineSkeleton_iter.Enable = 'off';
            else
                app.Button_RefineSkeleton_iter.Enable = 'on';
            end

            if isempty(app.Skeleton_refined) % nút xoá tay
                app.Button_manual_remove.Enable = 'off';
            else
                app.Button_manual_remove.Enable = 'on';
            end

            % nút nối tay
            if isempty(app.Skeleton_refined) % nút xoá tay
                app.Button_manual_link.Enable = 'off';
            else
                app.Button_manual_link.Enable = 'on';
            end

        end

        %%       Các hàm zernike

        function Z = zernfun(app, n, m, x, y)
            % Zernike polynomial of order (n,m) at points (x,y) on unit disk
            % x, y: same size vectors or matrices
            % n, m: integers, n >= 0, |m| <= n, n-m chẵn
            [theta, r] = cart2pol(x, y);
            if m >= 0
                Z = app.zernikeRadial(n, m, r) .* cos(m*theta);
            else
                Z = app.zernikeRadial(n, -m, r) .* sin(-m*theta);
            end
            Z(r>1) = 0;
        end

        %%
        function R = zernikeRadial(app, n, m, r)
            % Radial polynomial R_n^m(r)
            R = zeros(size(r));
            for s = 0:((n-m)/2)
                c = (-1)^s * factorial(n-s) / (factorial(s)*factorial((n+m)/2-s)*factorial((n-m)/2-s));
                R = R + c * r.^(n-2*s);
            end
        end
        %%
        function [nList, mList] = zernikeOrders(app, maxN)
            % Output vector nList, mList for all Zernike terms up to order maxN
            nList = [];
            mList = [];
            for n = 0:maxN
                for m = -n:2:n
                    nList(end+1) = n;
                    mList(end+1) = m;
                end
            end
        end
        %%
    end % end method


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown_FilterMethod.Items = {'Không', 'Gaussian', 'Median', 'Wiener', 'All'};
            app.DropDown_FilterMethod.Value = 'Không';

            app.DropDown_ContrastEnhance.Items = {'Không', 'imadjust', 'histeq', 'adapthisteq'};
            app.DropDown_ContrastEnhance.Value = 'Không';

            app.DropDown_BackgroundNorm.Items = {'Không', 'GaussianSubtract'};
            app.DropDown_BackgroundNorm.Value = 'Không';

            app.DropDown_manual_remove.Items = {'Freehand', 'HCN'};
            app.DropDown_manual_remove.Value = 'Freehand';


            app.DropDown_manual_remove.Items = {'None', 'Invert'};
            app.DropDown_manual_remove.Value = 'None';

            
            app.DropDown_reconstruc_surface.Items = {'None', 'Remove'};
            app.DropDown_reconstruc_surface.Value = 'None';

            % Gọi các hàm để cập nhật label lúc khởi động
            DropDown_FilterMethodValueChanged(app, []);
            DropDown_BackgroundNormValueChanged(app, []);
            DropDown_ContrastEnhanceValueChanged(app, []);

            DropDown_manual_removeValueChanged(app, []);
            
            DropDown_remove_tiltValueChanged(app, []);
            DropDown_reconstruc_surfaceValueChanged(app,[]);

            
            %% cập nhật hiển thị nút bấm
            updateButtonVisible(app);

            %             % Thiết lập giá trị mặc định cho EditField_file_path
            %             app.EditField_file_path.Value = 'C:\Users\admin\Máy tính\Lab thầy Tùng\Tài liệu a Tuân\Ảnh mẫu\anh_nham_chuan.bmp';
        end

        % Button pushed function: Button_add_Img
        function Button_add_ImgPushed(app, event)

            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Ảnh (*.jpg, *.png, *.bmp)'}, ...
                'Chọn ảnh');
            if isequal(file, 0)
                return;
            end

            % Đưa app lên trước sau khi chọn xong
            figure(app.UIFigure);  % hoặc movegui(app.UIFigure)

            % Lưu đường dẫn
            fullPath = fullfile(path, file);
            app.EditField_file_path.Value = fullPath;
            app.Label_image_name.Text = file;

            % Đọc ảnh gốc
            img_original = imread(fullPath);

            % Chuyển ảnh sang grayscale và kiểu double
            app.grayImg = app.convertToGray(img_original);

            % Hiển thị ảnh sau khi đã xử lý

            imshow(app.grayImg, 'Parent', app.UIAxes_display_Img);


        end

        % Button pushed function: Button_crop_Img
        function Button_crop_ImgPushed(app, event)

            % Kiểm tra có ảnh đầu vào hay không
            if isempty(app.grayImg)
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào. Vui lòng chọn ảnh trước.', 'Lỗi');
                return;
            end

            % Mở cửa sổ mới để người dùng chọn vùng cần cắt
            figure; imshow(app.grayImg);
            title('Chọn vùng để cắt, sau đó nhấn Enter');
            rect = getrect;  % Chờ người dùng chọn vùng
            close;

            % Cắt ảnh và hiển thị kết quả
            croppedImg = imcrop(app.grayImg, rect);
            imshow(croppedImg, 'Parent', app.UIAxes_crop_Img);

            % Lưu lại ảnh đã cắt vào thuộc tính của app
            app.CroppedImage = croppedImg;
            app.temp_var = croppedImg;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Hiện hộp thoại xác nhận
            choice = uiconfirm(app.UIFigure, ...
                'Bạn có chắc chắn muốn thoát?', ...
                'Thoát ứng dụng', ...
                'Options', {'Thoát','Huỷ'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if strcmp(choice, 'Thoát')
                % Đóng tất cả figure ngoại trừ app chính
                figs = findall(0, 'Type', 'figure');
                for k = 1:length(figs)
                    if figs(k) ~= app.UIFigure
                        try
                            close(figs(k));
                        catch
                            % Nếu có lỗi khi đóng thì bỏ qua
                        end
                    end
                end
                delete(app);  % Đóng ứng dụng
            end

        end

        % Button pushed function: Button_Enhance
        function Button_EnhancePushed(app, event)

            if isempty(app.grayImg)
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào.', 'Lỗi');
                return;
            end

            % Lấy các lựa chọn từ dropdown
            filterMethod = app.DropDown_FilterMethod.Value;
            contrastMethod = app.DropDown_ContrastEnhance.Value;
            bgNormMethod = app.DropDown_BackgroundNorm.Value;

            % Nếu ảnh được cropped thì xử lý ảnh cropped, ...
            % Nếu không thì xử lý ảnh gray ban đầu
            if(isempty(app.CroppedImage))
                img = app.grayImg;
            else
                img = app.CroppedImage;
            end

            %% --- Bước 1: Lọc nhiễu ---
            switch filterMethod
                case 'Gaussian'
                    img = imgaussfilt(img, 1);
                case 'Median'
                    img = medfilt2(img, [3 3]);
                case 'Wiener'
                    img = wiener2(img, [5 5]);
                case 'All'
                    img = imgaussfilt(img, 1);
                    img = medfilt2(img, [3 3]);
                    img = wiener2(img, [5 5]);
                otherwise
                    % Không làm gì
            end

            %% --- Bước 2: Chuẩn hoá nền ---
            switch bgNormMethod
                case 'GaussianSubtract'
                    bg = imgaussfilt(img, 20);
                    img = img - bg;
                    img = mat2gray(img);
                otherwise
                    % Không chuẩn hóa
            end

            %% --- Bước 3: Tăng cường tương phản ---
            switch contrastMethod
                case 'imadjust' % Tăng cường tương phản bằng cách điều chỉnh độ sáng (intensity adjustment).
                    img = imadjust(img);
                case 'histeq' % Cân bằng histogram để tăng độ tương phản.
                    img = histeq(img);
                case 'adapthisteq'
                    % Cân bằng histogram thích nghi (CLAHE) giúp tăng tương
                    % phản cục bộ, tốt cho ảnh có vùng sáng tối khác nhau
                    img = adapthisteq(img);
                otherwise
                    % Không tăng cường
            end

            %% --- Hiển thị kết quả ---
            app.ProcessedImage = img;
            imshow(img, 'Parent', app.UIAxes_enhance_Img);


        end

        % Value changed function: DropDown_FilterMethod
        function DropDown_FilterMethodValueChanged(app, event)

            selectedFilter = app.DropDown_FilterMethod.Value;

            switch selectedFilter
                case 'None'
                    msg = 'Không dùng bộ lọc';
                case 'Gaussian'
                    msg = 'Gaussian để làm mờ ảnh, giảm nhiễu nhẹ';
                case 'Median'
                    msg = 'lọc Median để bỏ salt & pepper noise';
                case 'Wiener'
                    msg = 'Wiener hiệu quả khi nhiễu Gaussian và nhiễu ngẫu nhiên';
                case 'All'
                    msg = 'Đang dùng tất cả bộ lọc';
                otherwise
                    msg = '';
            end

            app.Label_func_filter.Text = msg;


        end

        % Value changed function: DropDown_BackgroundNorm
        function DropDown_BackgroundNormValueChanged(app, event)

            selectedBG = app.DropDown_BackgroundNorm.Value;

            switch selectedBG
                case 'None'
                    msg = 'Không chuẩn hoá nền';
                case 'GaussianSubtract'
                    msg = 'Chuẩn hoá nền bằng Gaussian';
                otherwise
                    msg = '';
            end

            app.Label_func_nen.Text = msg;

        end

        % Value changed function: DropDown_ContrastEnhance
        function DropDown_ContrastEnhanceValueChanged(app, event)

            selectedContrast = app.DropDown_ContrastEnhance.Value;

            switch selectedContrast
                case 'None'
                    msg = 'Không tăng cường tương phản';
                case 'imadjust'
                    msg = 'Tăng tương phản bằng điều chỉnh độ sáng (intensity adjustment)';
                case 'histeq'
                    msg = 'Cân bằng histogram để tăng độ tương phản';
                case 'adapthisteq'
                    msg = ['Cân bằng histogram thích nghi (CLAHE) giúp tăng' ...
                        'tương phản cục bộ, tốt cho ảnh có vùng sáng tối khác nhau.'];
                otherwise
                    msg = '';
            end

            app.Label_func_constrast.Text = msg;


        end

        % Button pushed function: Button_show_enhance
        function Button_show_enhancePushed(app, event)
            if(isempty(app.ProcessedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được xử lý.', 'Lỗi');
                return;
            else
                figure(3);
                imshow(app.ProcessedImage);
                title("Ảnh đã được xử lý");
            end

        end

        % Button pushed function: Button_show_cropped
        function Button_show_croppedPushed(app, event)
            if(isempty(app.CroppedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được cropped.', 'Lỗi');
                return;
            else
                figure(2);
                imshow(app.CroppedImage);
                title("Ảnh đã được cropped");
            end
        end

        % Button pushed function: Button_show_img_orig
        function Button_show_img_origPushed(app, event)
            if(isempty(app.grayImg))
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào.', 'Lỗi');
                return;
            else
                figure(1);
                imshow(app.grayImg);
                title("Ảnh đầu vào Gray Scale");

            end
        end

        % Button pushed function: Button_skeletonize
        function Button_skeletonizePushed(app, event)
            % Kiểm tra ảnh đầu vào theo thứ tự ưu tiên: Processed > Cropped > Gray
            if ~isempty(app.ProcessedImage)
                input_img = app.ProcessedImage;
            elseif ~isempty(app.CroppedImage)
                input_img = app.CroppedImage;
            elseif ~isempty(app.grayImg)
                input_img = app.grayImg;
            else
                uialert(app.UIFigure, 'Chưa có ảnh để skeletonize.', 'Lỗi');
                return;
            end

            % Hiển thị hộp thoại tiến trình (không có nút Hủy)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện skeletonize ảnh...', ...
                'Indeterminate', 'on');

            try
                % --- Bước 1: Nhị phân hóa ảnh bằng Otsu ---
                thresh = graythresh(input_img);
                BW_Original = ~imbinarize(input_img, thresh); % đảo ngược

                % --- Bước 2: Skeletonize bằng Zhang-Suen ---
                BW_Thinned = BW_Original;
                [rows, cols] = size(BW_Thinned);
                changing = true;

                while changing
                    changing = false;
                    BW_Del = true(rows, cols);

                    % --- Step 1 ---
                    for i = 2:rows-1
                        for j = 2:cols-1
                            P = BW_Thinned(i-1:i+1, j-1:j+1);
                            P = P(:)';
                            P = [P(5), P(2), P(3), P(6), P(9), P(8), P(7), P(4), P(1), P(2)];

                            if P(1) == 1
                                neighbors = sum(P(2:9));
                                transitions = sum(P(2:9) == 0 & P(3:10) == 1);

                                if neighbors >= 2 && neighbors <= 6 && transitions == 1 ...
                                        && P(2)*P(4)*P(6) == 0 && P(4)*P(6)*P(8) == 0
                                    BW_Del(i,j) = false;
                                    changing = true;
                                end
                            end
                        end
                    end
                    BW_Thinned = BW_Thinned & BW_Del;

                    % --- Step 2 ---
                    BW_Del = true(rows, cols);
                    for i = 2:rows-1
                        for j = 2:cols-1
                            P = BW_Thinned(i-1:i+1, j-1:j+1);
                            P = P(:)';
                            P = [P(5), P(2), P(3), P(6), P(9), P(8), P(7), P(4), P(1), P(2)];

                            if P(1) == 1
                                neighbors = sum(P(2:9));
                                transitions = sum(P(2:9) == 0 & P(3:10) == 1);

                                if neighbors >= 2 && neighbors <= 6 && transitions == 1 ...
                                        && P(2)*P(4)*P(8) == 0 && P(2)*P(6)*P(8) == 0
                                    BW_Del(i,j) = false;
                                    changing = true;
                                end
                            end
                        end
                    end
                    BW_Thinned = BW_Thinned & BW_Del;
                end

                % --- Bước 3: Hiển thị kết quả ---
                imshow(BW_Thinned, 'Parent', app.UIAxes_Skeleton);

                % Lưu kết quả
                app.Skeleton = BW_Thinned;
                app.temp_var = BW_Thinned;

            catch ME
                uialert(app.UIFigure, "Lỗi trong quá trình skeletonize: " + ME.message, "Lỗi");
            end

            % Đóng hộp thoại tiến trình
            close(d);


        end

        % Button pushed function: Button_show_skeletonization
        function Button_show_skeletonizationPushed(app, event)
            if(isempty(app.ProcessedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được xử lý.', 'Lỗi');
                return;
            else
                figure(4);
                imshow(app.Skeleton);
                title("Ảnh Skeleton");

            end
        end

        % Button pushed function: Button_RefineSkeleton
        function Button_RefineSkeletonPushed(app, event)

            if isempty(app.Skeleton)
                uialert(app.UIFigure, 'Chưa có ảnh Skeleton đầu vào.', 'Lỗi');
                return;
            end

            % Hiển thị hộp thoại thông báo đang xử lý
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện refine skeleton ảnh, vui lòng chờ...', ...
                'Indeterminate', 'on');

            try
                %                 % Chuyển ảnh sang nhị phân
                %                 binaryImg = imbinarize(app.Skeleton);

                % Gọi hàm tinh chỉnh skeleton
                refined = app.refineSkeleton(app.Skeleton);

                % Lưu lại skeleton đã refine
                app.Skeleton_refined = refined;

                %
                if isempty(app.Skeleton_refined)
                    app.Button_RefineSkeleton_iter.Enable = 'off'; % Ẩn nút
                    app.Button_manual_remove.Enable = 'off';
                    app.Button_manual_link.Enable = 'off';

                else
                    app.Button_RefineSkeleton_iter.Enable = 'on';  % Hiện nút
                    app.Button_manual_remove.Enable = 'on';
                    app.Button_manual_link.Enable = 'on';
                end


                % Hiển thị ảnh kết quả
                figure(5);
                imshow(refined); title("Ảnh sau khi refine skeleton");


            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi xử lý ảnh:\n%s', ME.message), 'Lỗi');
            end

            % Đóng progress dialog
            close(d);

        end

        % Button pushed function: Button_RefineSkeleton_iter
        function Button_RefineSkeleton_iterPushed(app, event)

            % Hiển thị hộp thoại thông báo đang xử lý
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện refine skeleton ảnh, vui lòng chờ...', ...
                'Indeterminate', 'on');

            try
                %                 % Chuyển ảnh sang nhị phân
                %                 binaryImg = imbinarize(app.Skeleton);

                % Gọi hàm tinh chỉnh skeleton
                refined = app.refineSkeleton(app.Skeleton_refined);

                % Lưu lại skeleton đã refine
                app.Skeleton_refined = refined;

                % Hiển thị ảnh kết quả
                figure(6);
                imshow(refined); title("Ảnh sau khi refine skeleton iter");


            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi xử lý ảnh:\n%s', ME.message), 'Lỗi');
            end
            % lưu giá trị trung gian
            app.temp_var = refined;
            % Đóng progress dialog
            close(d);
        end

        % Button pushed function: Button_manual_remove
        function Button_manual_removePushed(app, event)


            % Lấy giá trị lựa chọn từ dropdown
            value = app.DropDown_manual_remove.Value;

            % Hiển thị ảnh để thao tác
            figure(app.figure_manual_remove);
            hImg = imshow(app.Skeleton_refined);
            axis image off;
            title('Vẽ vùng muốn xóa (nhấn Enter để kết thúc)');
            hold on;

            % Khởi tạo mặt nạ tổng
            maskTotal = false(size(app.Skeleton_refined));

            disp('Vẽ các vùng cần xóa, nhấn Enter để kết thúc.');

            while true
                % Nếu người dùng đóng cửa sổ thì thoát
                if ~ishandle(app.figure_manual_remove)
                    disp('Cửa sổ đã bị đóng, hủy thao tác.');
                    return;
                end

                w = waitforbuttonpress;
                if w == 1  % Nhấn phím
                    key = get(gcf, 'CurrentCharacter');
                    if strcmp(key, char(13))  % Enter
                        break;
                    end
                else
                    switch value
                        case 'Freehand'
                            % Vẽ tự do
                            h = drawfreehand('Color', 'r', 'LineWidth', 1.5);

                        case 'HCN'
                            % Vẽ hình chữ nhật
                            h = drawrectangle('Color', 'b', 'LineWidth', 1.5);

                        otherwise
                            uialert(app.UIFigure, 'Chưa chọn kiểu xóa hợp lệ.', 'Lỗi');
                            return;
                    end

                    % Nếu không có vùng hợp lệ thì bỏ qua
                    if isempty(h) || isempty(h.Position)
                        continue;
                    end

                    % Tạo mặt nạ từ vùng đã vẽ
                    mask = createMask(h, hImg);

                    delete(h);  % Xóa object sau khi lấy mask

                    % Cộng dồn mask
                    maskTotal = maskTotal | mask;

                    % Hiển thị vùng vừa chọn
                    visboundaries(mask, 'Color', 'r');
                end
            end

            % Cập nhật skeleton
            app.Skeleton_refined(maskTotal) = 0;

            % Hiển thị kết quả
            figure(app.figure_manual_remove);
            imshow(app.Skeleton_refined);
            axis image off;
            title('Ảnh sau khi xóa thủ công');

            % luu bien trung gian
            app.temp_var = app.Skeleton_refined;


        end

        % Button pushed function: Button_manual_link
        function Button_manual_linkPushed(app, event)
            %             % Hiển thị ảnh skeleton đã tinh chỉnh
            %             figure(app.figure_manual_link);
            %             hImg = imshow(app.Skeleton_refined);
            %              axis image off;
            %             title('Click nhiều điểm để nối vân, nhấn Enter để kết thúc');
            %             hold on;
            %
            %             maskTotal = false(size(app.Skeleton_refined));  % Mặt nạ tổng
            %
            %             disp('Click nối các điểm, nhấn Enter khi hoàn thành.');
            %
            %             pts = [];  % Lưu tọa độ các điểm được click
            %
            %             while true
            %                 % Đợi click hoặc phím
            %                 [x, y, button] = ginput(1);
            %                 if isempty(button) || button == 13  % Enter hoặc không còn thao tác
            %                     break;
            %                 end
            %
            %                 % Lưu điểm được click
            %                 pts = [pts; x, y];
            %
            %                 % Vẽ điểm và đường nối ngay trên ảnh
            %                 plot(x, y, 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            %                 if size(pts,1) > 1
            %                     plot(pts(end-1:end,1), pts(end-1:end,2), 'g-', 'LineWidth', 2);
            %                 end
            %             end
            %
            %             if size(pts,1) < 2
            %                 disp('Phải chọn ít nhất 2 điểm để nối.');
            %                 return;
            %             end
            %
            %             % Làm mịn đường nối bằng cách nội suy spline (tùy chọn)
            %             t = 1:size(pts,1);
            %             tt = linspace(1, size(pts,1), 10*size(pts,1));
            %             xq = interp1(t, pts(:,1), tt, 'spline');
            %             yq = interp1(t, pts(:,2), tt, 'spline');
            %
            %             % Tạo mask vùng nối vân bằng poly2mask
            %             % Tạo vùng nối vân rộng (ví dụ bề rộng 3 pixel)
            %             width = 1;
            %             mask = false(size(app.Skeleton_refined));
            %
            %             for i = 1:length(xq)-1
            %                 % Khoảng giữa 2 điểm
            %                 x_line = linspace(xq(i), xq(i+1), 10);
            %                 y_line = linspace(yq(i), yq(i+1), 10);
            %
            %                 for j = 1:length(x_line)
            %                     % Tạo vùng tròn nhỏ quanh điểm nối
            %                     [rr, cc] = meshgrid(1:size(mask,2), 1:size(mask,1));
            %                     dist = sqrt((cc - y_line(j)).^2 + (rr - x_line(j)).^2);
            %                     mask = mask | (dist <= width);
            %                 end
            %             end
            %
            %             % Cộng vùng mask vào ảnh skeleton
            %             app.Skeleton_refined = app.Skeleton_refined | mask;
            %
            %             % Hiển thị ảnh sau khi nối
            %             figure(app.figure_manual_link);
            %             imshow(app.Skeleton_refined);
            %             axis image off;
            %             title('Ảnh sau khi nối vân thủ công');

            figure(app.figure_manual_link);
            hImg = imshow(app.Skeleton_refined);  % Lấy handle ảnh để dùng với createMask
            axis image off;
            title('Vẽ đường nối vân (Enter để kết thúc)');
            hold on;

            disp('Vẽ đường cần nối bằng tay. Nhấn Enter để kết thúc.');

            while true
                % Nếu người dùng đóng cửa sổ thì thoát
                if ~ishandle(app.figure_manual_link)
                    disp('Cửa sổ đã bị đóng. Hủy thao tác.');
                    return;
                end

                % Chờ phím hoặc chuột
                w = waitforbuttonpress;
                if w == 1  % Người dùng nhấn phím
                    key = get(gcf, 'CurrentCharacter');
                    if strcmp(key, char(13))  % Nhấn phím Enter
                        break;
                    end
                else
                    % Vẽ đường polyline không khép kín
                    h = drawpolyline('Color', 'g', 'LineWidth', 2);

                    if isempty(h.Position)
                        continue;
                    end

                    % Tạo mặt nạ từ đường vẽ (có thể rộng hơn bằng cách dùng imdilate nếu cần)
                    mask = createMask(h, hImg);  % Truyền handle ảnh để tránh lỗi ambiguity
                    delete(h);  % Xóa đối tượng để đỡ rối

                    % Nối vân bằng cách gán giá trị 1 (foreground) vào ảnh skeleton
                    app.Skeleton_refined(mask) = 1;

                    % Hiển thị lại đường vừa nối
                    visboundaries(mask, 'Color', 'g');
                end
            end

            % Hiển thị kết quả sau khi nối
            figure(app.figure_manual_link);
            imshow(app.Skeleton_refined);
            axis image off;
            title('Ảnh sau khi nối vân bằng tay');

            % luu bien trung gian
            app.temp_var = app.Skeleton_refined;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close request function: UIFigure
            figs = findall(0, 'Type', 'figure');
            for k = 1:length(figs)
                if figs(k) ~= app.UIFigure
                    try
                        close(figs(k));
                    catch
                        % Bỏ qua nếu không đóng được
                    end
                end
            end

            % Xóa app nếu cần
            delete(app);

        end

        % Value changed function: DropDown_manual_remove
        function DropDown_manual_removeValueChanged(app, event)
            value = app.DropDown_manual_remove.Value;

        end

        % Button pushed function: Button_fringe_order
        function Button_fringe_orderPushed(app, event)

            % Cắt biên ảnh để tránh ảnh hưởng vùng biên
            offset = 20;
            bw_crop = app.temp_var(offset:end-offset, offset:end-offset);
            [H, W] = size(bw_crop);

            % Tìm các vùng liên thông (vân)
            cc = bwconncomp(bw_crop);
            labeled_matrix = labelmatrix(cc);
            stats = regionprops(cc, 'Centroid');

            % Tìm nhóm gần tâm nhất làm gốc
            centroids = cat(1, stats.Centroid);
            dist = vecnorm(centroids - [W/2, H/2], 2, 2);
            [~, idx_center] = min(dist);

            % Khởi tạo vector nhãn với NaN
            labels = nan(cc.NumObjects, 1);
            labels(idx_center) = 0; % Nhóm gốc đặt nhãn 0

            queue = idx_center; % Hàng đợi để duyệt lan truyền nhãn
            processed_groups = false(cc.NumObjects, 1);
            processed_groups(idx_center) = true;

            while ~isempty(queue)
                current_group = queue(1);
                queue(1) = [];

                current_label = labels(current_group);
                pixels = cc.PixelIdxList{current_group};
                [rows, cols] = ind2sub([H, W], pixels);

                visited_gid = []; % Tránh xét lại nhóm cùng vòng lặp

                for i = 1:length(rows)
                    r = rows(i); c = cols(i);

                    % Lan truyền lên trên theo cột
                    for y = r-1:-1:1
                        gid = labeled_matrix(y, c);
                        if gid > 0 && ~processed_groups(gid) && ~ismember(gid, visited_gid)
                            labels(gid) = current_label + 1; % Nhãn tăng dần lên trên
                            queue(end+1) = gid;
                            processed_groups(gid) = true;
                            visited_gid(end+1) = gid;
                            break;
                        elseif gid > 0 && processed_groups(gid)
                            break;
                        end
                    end

                    % Lan truyền xuống dưới theo cột
                    for y = r+1:H
                        gid = labeled_matrix(y, c);
                        if gid > 0 && ~processed_groups(gid) && ~ismember(gid, visited_gid)
                            labels(gid) = current_label - 1; % Nhãn giảm dần xuống dưới
                            queue(end+1) = gid;
                            processed_groups(gid) = true;
                            visited_gid(end+1) = gid;
                            break;
                        elseif gid > 0 && processed_groups(gid)
                            break;
                        end
                    end
                end
            end

            % Chuẩn hóa nhãn thành số nguyên dương bắt đầu từ 1
            valid_labels = labels(~isnan(labels));
            unique_labels = unique(valid_labels);
            labels_new = nan(size(labels));
            for i = 1:length(unique_labels)
                labels_new(labels == unique_labels(i)) = i;
            end
            labels = labels_new;

            % Hiển thị kết quả với nhãn trên hình
            figure(app.figure_fringe_order); imshow(bw_crop); hold on;
            for k = 1:cc.NumObjects
                if ~isnan(labels(k))
                    c = stats(k).Centroid;
                    text(c(1), c(2), num2str(labels(k)), 'Color', 'yellow', 'FontSize', 9);
                end
            end
            title('Gán nhãn vân bằng lan truyền theo cột từ tâm');
            hold off;

            % Lưu kết quả về app
            app.temp_var = bw_crop;
            app.fringe_order = cc.NumObjects;
            app.fringe_labels = labels;

        end

        % Button pushed function: Button_reconstruct_surface
        function Button_reconstruct_surfacePushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang dựng lại bề mặt...', ...
                'Message', 'Vui lòng chờ trong giây lát...', ...
                'Indeterminate', 'on');

            try
                % Thông số vật lý
   
                avg_angle = 0;     % góc trung bình (độ)
                fringe_spacing = (app.lambda / 2) / cosd(abs(avg_angle)); % khoảng cách vân

                BW = app.temp_var;
                [H, W] = size(BW);
                cc = bwconncomp(BW);
                labeled_matrix = labelmatrix(cc);

                % Lấy nhãn đã gán từ bước trước
                if isprop(app, 'fringe_labels')
                    labels = app.fringe_labels;
                else
                    labels = (1:cc.NumObjects)'; % fallback
                end

                % Tạo dữ liệu 3D cho từng pixel của từng nhóm vân
                X = []; Y = []; Z = [];
                stats = regionprops(cc, 'PixelIdxList');
                for i = 1:cc.NumObjects
                    if ~isnan(labels(i))
                        pixels = stats(i).PixelIdxList;
                        [y, x] = ind2sub([H, W], pixels);
                        z = ones(size(x)) * labels(i) * fringe_spacing;
                        X = [X; x(:)];
                        Y = [Y; y(:)];
                        Z = [Z; z(:)];
                    end
                end

                % Chuẩn hóa tọa độ vào miền [-1,1] và lọc điểm trong vòng tròn đơn vị
                Xn = 2 * (X - min(X)) / (max(X) - min(X)) - 1;
                Yn = 2 * (Y - min(Y)) / (max(Y) - min(Y)) - 1;
                R = sqrt(Xn.^2 + Yn.^2);
                inside = R <= 1;
                Xn = Xn(inside);
                Yn = Yn(inside);
                Zn = Z(inside);

                % Fit đa thức Zernike
                maxN = 10;
                B = [];
                [nv, mv] = app.zernikeOrders(maxN);
                for i = 1:length(nv)
                    Zm = app.zernfun(nv(i), mv(i), Xn, Yn);
                    B = [B, Zm(:)];
                end
                c = B \ Zn;

                % Loại bỏ piston, tip, tilt
                if strcmpi(app.DropDown_reconstruc_surface.Value, 'Remove')
                    for i = 1:length(c)
                        if (nv(i) == 0 && mv(i) == 0) || (nv(i) == 1 && abs(mv(i)) == 1)
                            c(i) = 0;
                        end
                    end
                end

                % Dựng lưới Zernike trên miền [-1,1]x[-1,1]
                [xq, yq] = meshgrid(linspace(-1, 1, W), linspace(-1, 1, H));
                rq = sqrt(xq.^2 + yq.^2);
                mask = rq <= 1;
                Zq = zeros(size(xq));
                for i = 1:length(c)
                    Zq = Zq + c(i) * app.zernfun(nv(i), mv(i), xq, yq);
                end
                Zq(~mask) = NaN;

%                 % Hiển thị kết quả
%                 figure(app.fiugre_reconstruct_surface);
%                 title( '3D Surface Reconstructed by Zernike Fit')
%                 surf(xq, yq, -Zq, 'EdgeColor', 'none');
%                 colormap turbo; colorbar;
%                 xlabel('X (norm)'); ylabel('Y (norm)'); zlabel('Height (m)');
%                 title('3D Surface Reconstructed from Fringes (Zernike fit)');
%                 cbar = colorbar;
%                 cbar.Label.String = 'Height (m)';

                % hiển thị kết quả rad
                phi_rad = (4 * pi / app.lambda) * Zq;  % Chuyển từ mét sang radian
                figure(app.fiugre_reconstruct_surface);
                surf(xq, yq, phi_rad, 'EdgeColor', 'none');
                colormap turbo; colorbar;
                xlabel('X (norm)'); ylabel('Y (norm)'); zlabel('Rad');
                title('3D Surface Reconstructed from Fringes (Rad)');
                cbar = colorbar;
                cbar.Label.String = 'rad';


                % Lưu kết quả lên app
                app.recons_surface = Zq;

            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi khi dựng bề mặt');
            end

            close(d);


        end

        % Button pushed function: Button_remove_tilt
        function Button_remove_tiltPushed(app, event)
            % Button pushed function: Button_remove_tilt
            crop_val = 20;
            Zq = app.recons_surface; % Bề mặt đã dựng bằng Zernike, bạn cần lưu nó vào app.Zq trước đó
            phi_rad = (4 * pi / app.lambda) * Zq;  % Chuyển từ mét sang radian
            Zq = phi_rad;
            % Cắt viền nếu đủ lớn
            if size(Zq,1) > 2*crop_val && size(Zq,2) > 2*crop_val
                Z = Zq(crop_val+1:end-crop_val, crop_val+1:end-crop_val);
            else
                Z = Zq;
            end

            [M, N] = size(Z);
            [xGrid, yGrid] = meshgrid(1:N, 1:M);
            z = Z(:);
            good = ~isnan(z); % Chỉ lấy điểm hợp lệ

            %% Fit mặt phẳng và loại bỏ nghiêng
            A = [xGrid(:), yGrid(:), ones(numel(z),1)];
            coeff = A(good,:) \ z(good);
            Z_fit = reshape(A * coeff, size(Z));
            Z_leveled = Z - Z_fit;

            %% Chuẩn hóa lại Z (bắt đầu từ 0, đảo chiều nếu cần)
            Z_inverted = -Z_leveled;
            Z_offset = Z_inverted - min(Z_inverted(:));
            % đảo ngược giá trị (bằng dropdown)
            if strcmpi(app.DropDown_remove_tilt.Value, 'Invert')
                Z_offset = -Z_offset;
            end

%             % Hiển thị kết quả (độ cao -mét)
%             figure(app.figure_remove_tilt);
%             surf(xGrid, yGrid, Z_offset, 'EdgeColor', 'none');
%             shading interp;
%             title('3D Surface after Tilt Removal (Leveled)');
%             xlabel('X (px)');
%             ylabel('Y (px)');
%             zlabel('Height (m)');
%             colormap parula;
%             colorbar;

            % Hiển thị kết quả (rad)
            figure(app.figure_remove_tilt);
            surf(xGrid, yGrid, Z_offset, 'EdgeColor', 'none');
            shading interp;
            title('3D Surface after Tilt Removal (Leveled)');
            xlabel('X (px)');
            ylabel('Y (px)');
            zlabel('Rad');
            colormap parula;
            colorbar;


        end

        % Value changed function: DropDown_remove_tilt
        function DropDown_remove_tiltValueChanged(app, event)
            value = app.DropDown_remove_tilt.Value;
            
        end

        % Value changed function: DropDown_reconstruc_surface
        function DropDown_reconstruc_surfaceValueChanged(app, event)
            value = app.DropDown_reconstruc_surface.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {561, 561, 561};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {561, 561};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {225, '1x', 228};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Sourcegithubcomtuanpd205459Label = uilabel(app.LeftPanel);
            app.Sourcegithubcomtuanpd205459Label.Text = 'Source: github.com/tuanpd205459';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.EditField_file_path = uieditfield(app.LeftPanel, 'text');
            app.Button_add_Img = uibutton(app.LeftPanel, 'push');
            app.Button_add_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_add_ImgPushed, true);
            app.Button_add_Img.Text = 'Chọn ảnh';
            app.nhchnLabel = uilabel(app.LeftPanel);
            app.nhchnLabel.Text = 'Ảnh đã chọn:';
            app.Label_image_name = uilabel(app.LeftPanel);
            app.Label_image_name.Text = 'Tên ảnh';
            app.Button_crop_Img = uibutton(app.LeftPanel, 'push');
            app.Button_crop_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_crop_ImgPushed, true);
            app.Button_crop_Img.Text = 'Crop ảnh';
            app.LcnhiuLabel = uilabel(app.LeftPanel);
            app.LcnhiuLabel.Text = 'Lọc nhiễu';
            app.DropDown_FilterMethod = uidropdown(app.LeftPanel);
            app.DropDown_FilterMethod.Items = {'Không', 'Gaussian', 'Median', 'Wiener', 'All'};
            app.DropDown_FilterMethod.ValueChangedFcn = createCallbackFcn(app, @DropDown_FilterMethodValueChanged, true);
            app.DropDown_FilterMethod.Value = 'Không';
            app.Label_func_filter = uilabel(app.LeftPanel);
            app.Label_func_filter.Text = 'Label_func_filter';
            app.ChunhannLabel = uilabel(app.LeftPanel);
            app.ChunhannLabel.Text = 'Chuẩn hóa nền';
            app.DropDown_BackgroundNorm = uidropdown(app.LeftPanel);
            app.DropDown_BackgroundNorm.Items = {'Không', 'imadjust', 'histeq', 'adapthisteq'};
            app.DropDown_BackgroundNorm.ValueChangedFcn = createCallbackFcn(app, @DropDown_BackgroundNormValueChanged, true);
            app.DropDown_BackgroundNorm.Value = 'Không';
            app.Label_func_nen = uilabel(app.LeftPanel);
            app.Label_func_nen.Text = 'Label_func_nen';
            app.TngphnLabel = uilabel(app.LeftPanel);
            app.TngphnLabel.Text = 'Tương phản';
            app.DropDown_ContrastEnhance = uidropdown(app.LeftPanel);
            app.DropDown_ContrastEnhance.Items = {'Không', 'GaussianSubtract'};
            app.DropDown_ContrastEnhance.ValueChangedFcn = createCallbackFcn(app, @DropDown_ContrastEnhanceValueChanged, true);
            app.DropDown_ContrastEnhance.Value = 'Không';
            app.Label_func_constrast = uilabel(app.LeftPanel);
            app.Label_func_constrast.Text = 'Label_func_constrast';
            app.Button_Enhance = uibutton(app.LeftPanel, 'push');
            app.Button_Enhance.ButtonPushedFcn = createCallbackFcn(app, @Button_EnhancePushed, true);
            app.Button_Enhance.Text = 'Xử lý ảnh';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes_display_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_display_Img, 'Ảnh ban đầu (gray scale)')
            zlabel(app.UIAxes_display_Img, 'Z')
            app.UIAxes_display_Img.XTick = [];
            app.UIAxes_display_Img.YTick = [];
            app.UIAxes_crop_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_crop_Img, 'Ảnh cropped')
            zlabel(app.UIAxes_crop_Img, 'Z')
            app.UIAxes_crop_Img.XTick = [];
            app.UIAxes_crop_Img.YTick = [];
            app.UIAxes_enhance_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_enhance_Img, 'Ảnh sau xử lý')
            zlabel(app.UIAxes_enhance_Img, 'Z')
            app.UIAxes_enhance_Img.XTick = [];
            app.UIAxes_enhance_Img.YTick = [];
            app.UIAxes_Skeleton = uiaxes(app.GridLayout2);
            title(app.UIAxes_Skeleton, 'Skeleton ')
            zlabel(app.UIAxes_Skeleton, 'Z')
            app.UIAxes_Skeleton.XTick = [];
            app.UIAxes_Skeleton.YTick = [];
            app.Button_show_img_orig = uibutton(app.GridLayout2, 'push');
            app.Button_show_img_orig.ButtonPushedFcn = createCallbackFcn(app, @Button_show_img_origPushed, true);
            app.Button_show_img_orig.Text = 'Show ảnh';
            app.Button_show_cropped = uibutton(app.GridLayout2, 'push');
            app.Button_show_cropped.ButtonPushedFcn = createCallbackFcn(app, @Button_show_croppedPushed, true);
            app.Button_show_cropped.Text = 'Show ảnh';
            app.Button_show_enhance = uibutton(app.GridLayout2, 'push');
            app.Button_show_enhance.ButtonPushedFcn = createCallbackFcn(app, @Button_show_enhancePushed, true);
            app.Button_show_enhance.Text = 'Show ảnh';
            app.Button_show_skeletonization = uibutton(app.GridLayout2, 'push');
            app.Button_show_skeletonization.ButtonPushedFcn = createCallbackFcn(app, @Button_show_skeletonizationPushed, true);
            app.Button_show_skeletonization.Text = 'Show ảnh';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Button_skeletonize = uibutton(app.GridLayout3, 'push');
            app.Button_skeletonize.ButtonPushedFcn = createCallbackFcn(app, @Button_skeletonizePushed, true);
            app.Button_skeletonize.Text = 'Skeletonize';
            app.Button_RefineSkeleton = uibutton(app.GridLayout3, 'push');
            app.Button_RefineSkeleton.ButtonPushedFcn = createCallbackFcn(app, @Button_RefineSkeletonPushed, true);
            app.Button_RefineSkeleton.Text = 'Refine Skeleton';
            app.Button_RefineSkeleton_iter = uibutton(app.GridLayout3, 'push');
            app.Button_RefineSkeleton_iter.ButtonPushedFcn = createCallbackFcn(app, @Button_RefineSkeleton_iterPushed, true);
            app.Button_RefineSkeleton_iter.Text = 'Iter Refine';
            app.Button_manual_remove = uibutton(app.GridLayout3, 'push');
            app.Button_manual_remove.ButtonPushedFcn = createCallbackFcn(app, @Button_manual_removePushed, true);
            app.Button_manual_remove.Text = 'Manual remove';
            app.Button_manual_link = uibutton(app.GridLayout3, 'push');
            app.Button_manual_link.ButtonPushedFcn = createCallbackFcn(app, @Button_manual_linkPushed, true);
            app.Button_manual_link.Text = 'Manual Link';
            app.DropDown_manual_remove = uidropdown(app.GridLayout3);
            app.DropDown_manual_remove.Items = {'Free hand', 'HCN'};
            app.DropDown_manual_remove.ValueChangedFcn = createCallbackFcn(app, @DropDown_manual_removeValueChanged, true);
            app.DropDown_manual_remove.Value = 'Free hand';
            app.Button_fringe_order = uibutton(app.GridLayout3, 'push');
            app.Button_fringe_order.ButtonPushedFcn = createCallbackFcn(app, @Button_fringe_orderPushed, true);
            app.Button_fringe_order.Text = 'Fringe Order';
            app.Button_reconstruct_surface = uibutton(app.GridLayout3, 'push');
            app.Button_reconstruct_surface.ButtonPushedFcn = createCallbackFcn(app, @Button_reconstruct_surfacePushed, true);
            app.Button_reconstruct_surface.Text = 'Reconstruct Surface';
            app.Button_remove_tilt = uibutton(app.GridLayout3, 'push');
            app.Button_remove_tilt.ButtonPushedFcn = createCallbackFcn(app, @Button_remove_tiltPushed, true);
            app.Button_remove_tilt.Text = 'Remove tilt';
            app.DropDown_remove_tilt = uidropdown(app.GridLayout3);
            app.DropDown_remove_tilt.Items = {'None', 'Invert'};
            app.DropDown_remove_tilt.ValueChangedFcn = createCallbackFcn(app, @DropDown_remove_tiltValueChanged, true);
            app.DropDown_remove_tilt.Value = 'None';
            app.DropDown_reconstruc_surface = uidropdown(app.GridLayout3);
            app.DropDown_reconstruc_surface.Items = {'None', 'Remove'};
            app.DropDown_reconstruc_surface.ValueChangedFcn = createCallbackFcn(app, @DropDown_reconstruc_surfaceValueChanged, true);
            app.DropDown_reconstruc_surface.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_fringe_detection_backup2_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end