classdef app1_fringe_detection_backup4_6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Button_export_var            matlab.ui.control.Button
        Button_Debug                 matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        Tab1                         matlab.ui.container.Tab
        Button_Enhance               matlab.ui.control.Button
        DropDown_ContrastEnhance     matlab.ui.control.DropDown
        TngphnLabel                  matlab.ui.control.Label
        DropDown_BackgroundNorm      matlab.ui.control.DropDown
        ChunhannLabel                matlab.ui.control.Label
        DropDown_FilterMethod        matlab.ui.control.DropDown
        LcnhiuLabel                  matlab.ui.control.Label
        Label_func_constrast         matlab.ui.control.Label
        Label_func_nen               matlab.ui.control.Label
        Label_func_filter            matlab.ui.control.Label
        Button_crop_Img              matlab.ui.control.Button
        Label_image_name             matlab.ui.control.Label
        nhchnLabel                   matlab.ui.control.Label
        Button_add_Img               matlab.ui.control.Button
        EditField_file_path          matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        Sourcegithubcomtuanpd205459Label  matlab.ui.control.Label
        Tab2                         matlab.ui.container.Tab
        Button_linking               matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Button_show_skeletonization  matlab.ui.control.Button
        Button_show_enhance          matlab.ui.control.Button
        Button_show_cropped          matlab.ui.control.Button
        Button_show_img_orig         matlab.ui.control.Button
        UIAxes_Skeleton              matlab.ui.control.UIAxes
        UIAxes_enhance_Img           matlab.ui.control.UIAxes
        UIAxes_crop_Img              matlab.ui.control.UIAxes
        UIAxes_display_Img           matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Button_remove_spurs          matlab.ui.control.Button
        DropDown_recons_linear       matlab.ui.control.DropDown
        Button_reconSurface_linear   matlab.ui.control.Button
        DropDown_reconstruc_surface  matlab.ui.control.DropDown
        DropDown_remove_tilt         matlab.ui.control.DropDown
        Button_remove_tilt           matlab.ui.control.Button
        Button_reconstruct_surface   matlab.ui.control.Button
        Button_fringe_order          matlab.ui.control.Button
        DropDown_manual_remove       matlab.ui.control.DropDown
        Button_manual_link           matlab.ui.control.Button
        Button_manual_remove         matlab.ui.control.Button
        Button_RefineSkeleton_iter   matlab.ui.control.Button
        Button_RefineSkeleton        matlab.ui.control.Button
        Button_skeletonize           matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        CroppedImage    % ảnh sau khi cắt
        grayImg         % ảnh gray scale
        ProcessedImage  % ảnh sau xử lý nâng cao
        Skeleton % ảnh xương
        Skeleton_refined % ảnh xương sau tinh chỉnh
        figure_refine = 6; % số thứ tự figure
        figure_manual_remove = 7; %số thứ tự ảnh manual remove
        figure_manual_link = 8 % số thứ tự ảnh manual link
        figure_fringe_order = 9; % STT ảnh fringe order
        fiugre_reconstruct_surface = 10; % STT anh tai tao
        figure_remove_tilt = 11; %STT anh remove tilt
        figure_reconstruct_surface_linear = 12 % STT ảnh tái tạo linear

        temp_var % biến lưu giữ giá trị trung gian qua các giai đoạn xử lý


        fringe_order % bậc vân
        recons_surface % bề mặt được tái tạo.

        fringe_labels % labels vân
        

        lambda = 632.8*1e-9 % bước sóng
    end

    methods (Access = private)

        function grayImg = convertToGray(~, Img_Original)
            if size(Img_Original, 3) == 3
                grayImg = rgb2gray(Img_Original);
            else
                grayImg = Img_Original;
            end
        end


        function anh_sach = loai_bo_nhieu(~, anh_nhi_phan)
            % LOAI_BO_NHIEU  Loại bỏ các điểm ảnh nhiễu đơn lẻ hoặc thò ra khỏi đường chính
            % Đầu vào:
            %   - anh_nhi_phan: ảnh nhị phân (0 và 1)
            % Đầu ra:
            %   - anh_sach: ảnh sau khi đã loại bỏ nhiễu

            % Kiểm tra đầu vào là ảnh nhị phân
            if ~islogical(anh_nhi_phan)
                anh_nhi_phan = imbinarize(anh_nhi_phan);
            end

            % Tạo phần tử cấu trúc 3x3
            se1 = strel('square', 3);

            % Đóng để nối các trace bị đứt
            anh_dong = imclose(anh_nhi_phan, se1);


            se2 = strel('square', 2);

            % Mở để loại bỏ các vùng nhỏ và nhiễu
            anh_mo = imopen(anh_dong, se2);

            % Xử lý thêm bằng bwmorph
            anh_clean = bwmorph(anh_mo, 'clean');  % Xoá điểm đơn lẻ
            anh_clean = bwmorph(anh_clean, 'spur');  % Xoá điểm thò ra

            % Kết quả cuối
            anh_sach = anh_clean;

        end


        %% func tinh chỉnh vân
        function refined = refineSkeleton(~, binaryImg)
            % refineSkeleton - Tinh chỉnh ảnh skeleton nhị phân
            % Đầu vào:
            %   binaryImg - ảnh nhị phân đã skeleton hóa
            % Đầu ra:
            %   refined - ảnh skeleton sau khi loại nhiễu và nối các đoạn đứt

            % --- Bước 1: Loại bỏ các nhánh nhỏ (râu ria)
            cleaned = bwmorph(binaryImg, 'spur', 1);  % loại bỏ các nhánh nhỏ lẻ

%             % --- Bước 2: Morphological closing để nối các đoạn đứt
            se = strel('disk', 2);                      % kernel nhỏ để tránh nối quá mức
            closed = imclose(cleaned, se);              % đóng các khe hở nhỏ

            % --- Bước 3: Lấy lại skeleton sau khi closing
            skeleton = bwmorph(closed, 'skel', 3);    % skeleton hóa lại

            % --- Bước 4: Loại bỏ tiếp râu ria còn lại (nếu có)
            pruned = bwmorph(skeleton, 'spur', 1);      % chỉ xóa các spur nhỏ nhất

            % --- Bước 5: Xoá các điểm trắng đơn lẻ (nhiễu nhỏ)
            refined = bwareaopen(pruned, 2);            % giữ lại các vùng >= 2 pixels
        end

        %% func tinh chỉnh vân
        function refined = xoa_ria(~, binaryImg)
            % refineSkeleton - Tinh chỉnh ảnh skeleton nhị phân
            % Đầu vào:
            %   binaryImg - ảnh nhị phân đã skeleton hóa
            % Đầu ra:
            %   refined - ảnh skeleton sau khi loại nhiễu và nối các đoạn đứt

            % --- Bước 1: Loại bỏ các nhánh nhỏ (râu ria)
            cleaned = bwmorph(binaryImg, 'spur', 1);  % loại bỏ các nhánh nhỏ lẻ

            % --- Bước 3: Lấy lại skeleton sau khi closing
            skeleton = bwmorph(cleaned, 'skel', 3);    % skeleton hóa lại

            % --- Bước 4: Loại bỏ tiếp râu ria còn lại (nếu có)
            pruned = bwmorph(skeleton, 'spur', 1);      % chỉ xóa các spur nhỏ nhất

            % --- Bước 5: Xoá các điểm trắng đơn lẻ (nhiễu nhỏ)
            refined = bwareaopen(pruned, 2);            % giữ lại các vùng >= 2 pixels
        end
        %%

        function updateButtonVisible(app)

            if isempty(app.Skeleton_refined) % nút lặp tinh chỉnh xương
                app.Button_RefineSkeleton_iter.Enable = 'off';
            else
                app.Button_RefineSkeleton_iter.Enable = 'on';
            end

            if isempty(app.Skeleton_refined) % nút xoá tay
                app.Button_manual_remove.Enable = 'off';
            else
                app.Button_manual_remove.Enable = 'on';
            end

            % nút nối tay
            if isempty(app.Skeleton_refined) % nút xoá tay
                app.Button_manual_link.Enable = 'off';
            else
                app.Button_manual_link.Enable = 'on';
            end

        end

        %%       Các hàm zernike

        function Z = zernfun(app, n, m, x, y)
            % Zernike polynomial of order (n,m) at points (x,y) on unit disk
            % x, y: same size vectors or matrices
            % n, m: integers, n >= 0, |m| <= n, n-m chẵn
            [theta, r] = cart2pol(x, y);
            if m >= 0
                Z = app.zernikeRadial(n, m, r) .* cos(m*theta);
            else
                Z = app.zernikeRadial(n, -m, r) .* sin(-m*theta);
            end
            Z(r>1) = 0;
        end

        %%
        function R = zernikeRadial(app, n, m, r)
            % Radial polynomial R_n^m(r)
            R = zeros(size(r));
            for s = 0:((n-m)/2)
                c = (-1)^s * factorial(n-s) / (factorial(s)*factorial((n+m)/2-s)*factorial((n-m)/2-s));
                R = R + c * r.^(n-2*s);
            end
        end
        %%
        function [nList, mList] = zernikeOrders(app, maxN)
            % Output vector nList, mList for all Zernike terms up to order maxN
            nList = [];
            mList = [];
            for n = 0:maxN
                for m = -n:2:n
                    nList(end+1) = n;
                    mList(end+1) = m;
                end
            end
        end
        %%
    end % end method


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown_FilterMethod.Items = {'Không', 'Gaussian', 'Median', 'Wiener', 'All'};
            app.DropDown_FilterMethod.Value = 'Không';

            app.DropDown_ContrastEnhance.Items = {'Không', 'imadjust', 'histeq', 'adapthisteq'};
            app.DropDown_ContrastEnhance.Value = 'Không';

            app.DropDown_BackgroundNorm.Items = {'Không', 'GaussianSubtract'};
            app.DropDown_BackgroundNorm.Value = 'Không';

            app.DropDown_manual_remove.Items = {'Freehand', 'HCN'};
            app.DropDown_manual_remove.Value = 'Freehand';


            app.DropDown_remove_tilt.Items = {'None', 'Invert'};
            app.DropDown_remove_tilt.Value = 'None';

            
            app.DropDown_reconstruc_surface.Items = {'None', 'Remove', 'Invert','Invert Remove'};
            app.DropDown_reconstruc_surface.Value = 'None';


            app.DropDown_recons_linear.Items = {'None', 'Invert', 'Remove tilt', 'Remove + Invert'};
            app.DropDown_recons_linear.Value = 'None';

            % Gọi các hàm để cập nhật label lúc khởi động
            DropDown_FilterMethodValueChanged(app, []);
            DropDown_BackgroundNormValueChanged(app, []);
            DropDown_ContrastEnhanceValueChanged(app, []);

            DropDown_manual_removeValueChanged(app, []);
            
            DropDown_remove_tiltValueChanged(app, []);
            DropDown_reconstruc_surfaceValueChanged(app,[]);


            %% cập nhật hiển thị nút bấm
            updateButtonVisible(app);

            %             % Thiết lập giá trị mặc định cho EditField_file_path
            %             app.EditField_file_path.Value = 'C:\Users\admin\Máy tính\Lab thầy Tùng\Tài liệu a Tuân\Ảnh mẫu\anh_nham_chuan.bmp';
        end

        % Button pushed function: Button_add_Img
        function Button_add_ImgPushed(app, event)

            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Ảnh (*.jpg, *.png, *.bmp)'}, ...
                'Chọn ảnh');
            if isequal(file, 0)
                return;
            end

            % Đưa app lên trước sau khi chọn xong
            figure(app.UIFigure);  % hoặc movegui(app.UIFigure)

            % Lưu đường dẫn
            fullPath = fullfile(path, file);
            app.EditField_file_path.Value = fullPath;
            app.Label_image_name.Text = file;

            % Đọc ảnh gốc
            img_original = imread(fullPath);

            % Chuyển ảnh sang grayscale và kiểu double
            app.grayImg = app.convertToGray(img_original);

            % Hiển thị ảnh sau khi đã xử lý

            imshow(app.grayImg, 'Parent', app.UIAxes_display_Img);


        end

        % Button pushed function: Button_crop_Img
        function Button_crop_ImgPushed(app, event)

            % Kiểm tra có ảnh đầu vào hay không
            if isempty(app.grayImg)
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào. Vui lòng chọn ảnh trước.', 'Lỗi');
                return;
            end

            % Mở cửa sổ mới để người dùng chọn vùng cần cắt
            figure; imshow(app.grayImg);
            title('Chọn vùng để cắt, sau đó nhấn Enter');
            rect = getrect;  % Chờ người dùng chọn vùng
            close;

            % Cắt ảnh và hiển thị kết quả
            croppedImg = imcrop(app.grayImg, rect);
            imshow(croppedImg, 'Parent', app.UIAxes_crop_Img);

            % Lưu lại ảnh đã cắt vào thuộc tính của app
            app.CroppedImage = croppedImg;
            app.temp_var = croppedImg;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Hiện hộp thoại xác nhận
            choice = uiconfirm(app.UIFigure, ...
                'Bạn có chắc chắn muốn thoát?', ...
                'Thoát ứng dụng', ...
                'Options', {'Thoát','Huỷ'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if strcmp(choice, 'Thoát')
                % Đóng tất cả figure ngoại trừ app chính
                figs = findall(0, 'Type', 'figure');
                for k = 1:length(figs)
                    if figs(k) ~= app.UIFigure
                        try
                            close(figs(k));
                        catch
                            % Nếu có lỗi khi đóng thì bỏ qua
                        end
                    end
                end
                delete(app);  % Đóng ứng dụng

            end

        end

        % Button pushed function: Button_Enhance
        function Button_EnhancePushed(app, event)

            if isempty(app.grayImg)
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào.', 'Lỗi');
                return;
            end

            % Lấy các lựa chọn từ dropdown
            filterMethod = app.DropDown_FilterMethod.Value;
            contrastMethod = app.DropDown_ContrastEnhance.Value;
            bgNormMethod = app.DropDown_BackgroundNorm.Value;

            % Nếu ảnh được cropped thì xử lý ảnh cropped, ...
            % Nếu không thì xử lý ảnh gray ban đầu
            if(isempty(app.CroppedImage))
                img = app.grayImg;
            else
                img = app.CroppedImage;
            end

            %% --- Bước 1: Lọc nhiễu ---
            switch filterMethod
                case 'Gaussian'
                    img = imgaussfilt(img, 1);
                case 'Median'
                    img = medfilt2(img, [3 3]);
                case 'Wiener'
                    img = wiener2(img, [5 5]);
                case 'All'
                    img = imgaussfilt(img, 1);
                    img = medfilt2(img, [3 3]);
                    img = wiener2(img, [5 5]);
                otherwise
                    % Không làm gì
            end

            %% --- Bước 2: Chuẩn hoá nền ---
            switch bgNormMethod
                case 'GaussianSubtract'
                    bg = imgaussfilt(img, 20);
                    img = img - bg;
                    img = mat2gray(img);
                otherwise
                    % Không chuẩn hóa
            end

            %% --- Bước 3: Tăng cường tương phản ---
            switch contrastMethod
                case 'imadjust' % Tăng cường tương phản bằng cách điều chỉnh độ sáng (intensity adjustment).
                    img = imadjust(img);
                case 'histeq' % Cân bằng histogram để tăng độ tương phản.
                    img = histeq(img);
                case 'adapthisteq'
                    % Cân bằng histogram thích nghi (CLAHE) giúp tăng tương
                    % phản cục bộ, tốt cho ảnh có vùng sáng tối khác nhau
                    img = adapthisteq(img);
                otherwise
                    % Không tăng cường
            end

            %% --- Hiển thị kết quả ---
            app.ProcessedImage = img;
            imshow(img, 'Parent', app.UIAxes_enhance_Img);


        end

        % Callback function
        function DropDown_FilterMethodValueChanged(app, event)

            selectedFilter = app.DropDown_FilterMethod.Value;

            switch selectedFilter
                case 'None'
                    msg = 'Không dùng bộ lọc';
                case 'Gaussian'
                    msg = 'Gaussian để làm mờ ảnh, giảm nhiễu nhẹ';
                case 'Median'
                    msg = 'lọc Median để bỏ salt & pepper noise';
                case 'Wiener'
                    msg = 'Wiener hiệu quả khi nhiễu Gaussian và nhiễu ngẫu nhiên';
                case 'All'
                    msg = 'Đang dùng tất cả bộ lọc';
                otherwise
                    msg = '';
            end

            app.Label_func_filter.Text = msg;


        end

        % Callback function
        function DropDown_BackgroundNormValueChanged(app, event)

            selectedBG = app.DropDown_BackgroundNorm.Value;

            switch selectedBG
                case 'None'
                    msg = 'Không chuẩn hoá nền';
                case 'GaussianSubtract'
                    msg = 'Chuẩn hoá nền bằng Gaussian';
                otherwise
                    msg = '';
            end

            app.Label_func_nen.Text = msg;

        end

        % Callback function
        function DropDown_ContrastEnhanceValueChanged(app, event)

            selectedContrast = app.DropDown_ContrastEnhance.Value;

            switch selectedContrast
                case 'None'
                    msg = 'Không tăng cường tương phản';
                case 'imadjust'
                    msg = 'Tăng tương phản bằng điều chỉnh độ sáng (intensity adjustment)';
                case 'histeq'
                    msg = 'Cân bằng histogram để tăng độ tương phản';
                case 'adapthisteq'
                    msg = ['Cân bằng histogram thích nghi (CLAHE) giúp tăng' ...
                        'tương phản cục bộ, tốt cho ảnh có vùng sáng tối khác nhau.'];
                otherwise
                    msg = '';
            end

            app.Label_func_constrast.Text = msg;


        end

        % Button pushed function: Button_show_enhance
        function Button_show_enhancePushed(app, event)
            if(isempty(app.ProcessedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được xử lý.', 'Lỗi');
                return;
            else
                figure(3);
                imshow(app.ProcessedImage);
                title("Ảnh đã được xử lý");
            end

        end

        % Button pushed function: Button_show_cropped
        function Button_show_croppedPushed(app, event)
            if(isempty(app.CroppedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được cropped.', 'Lỗi');
                return;
            else
                figure(2);
                imshow(app.CroppedImage);
                title("Ảnh đã được cropped");
            end
        end

        % Button pushed function: Button_show_img_orig
        function Button_show_img_origPushed(app, event)
            if(isempty(app.grayImg))
                uialert(app.UIFigure, 'Chưa có ảnh đầu vào.', 'Lỗi');
                return;
            else
                figure(1);
                imshow(app.grayImg);
                title("Ảnh đầu vào Gray Scale");

            end
        end

        % Button pushed function: Button_skeletonize
        function Button_skeletonizePushed(app, event)
            % Kiểm tra ảnh đầu vào theo thứ tự ưu tiên: Processed > Cropped > Gray
            if ~isempty(app.ProcessedImage)
                input_img = app.ProcessedImage;
            elseif ~isempty(app.CroppedImage)
                input_img = app.CroppedImage;
            elseif ~isempty(app.grayImg)
                input_img = app.grayImg;
            else
                uialert(app.UIFigure, 'Chưa có ảnh để skeletonize.', 'Lỗi');
                return;
            end

            % Hiển thị hộp thoại tiến trình (không có nút Hủy)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện skeletonize ảnh...', ...
                'Indeterminate', 'on');

            try
                % --- Bước 1: Nhị phân hóa ảnh bằng Otsu ---
                thresh = graythresh(input_img);
                BW_Original = imbinarize(input_img, thresh); % đảo ngược

                % --- Bước 2: Skeletonize bằng Zhang-Suen ---
                BW_Thinned = BW_Original;
                [rows, cols] = size(BW_Thinned);
                changing = true;

                while changing
                    changing = false;
                    BW_Del = true(rows, cols);

                    % --- Step 1 ---
                    for i = 2:rows-1
                        for j = 2:cols-1
                            P = BW_Thinned(i-1:i+1, j-1:j+1);
                            P = P(:)';
                            P = [P(5), P(2), P(3), P(6), P(9), P(8), P(7), P(4), P(1), P(2)];

                            if P(1) == 1
                                neighbors = sum(P(2:9));
                                transitions = sum(P(2:9) == 0 & P(3:10) == 1);

                                if neighbors >= 2 && neighbors <= 6 && transitions == 1 ...
                                        && P(2)*P(4)*P(6) == 0 && P(4)*P(6)*P(8) == 0
                                    BW_Del(i,j) = false;
                                    changing = true;
                                end
                            end
                        end
                    end
                    BW_Thinned = BW_Thinned & BW_Del;

                    % --- Step 2 ---
                    BW_Del = true(rows, cols);
                    for i = 2:rows-1
                        for j = 2:cols-1
                            P = BW_Thinned(i-1:i+1, j-1:j+1);
                            P = P(:)';
                            P = [P(5), P(2), P(3), P(6), P(9), P(8), P(7), P(4), P(1), P(2)];

                            if P(1) == 1
                                neighbors = sum(P(2:9));
                                transitions = sum(P(2:9) == 0 & P(3:10) == 1);

                                if neighbors >= 2 && neighbors <= 6 && transitions == 1 ...
                                        && P(2)*P(4)*P(8) == 0 && P(2)*P(6)*P(8) == 0
                                    BW_Del(i,j) = false;
                                    changing = true;
                                end
                            end
                        end
                    end
                    BW_Thinned = BW_Thinned & BW_Del;
                end

                % --- Bước 3: Hiển thị kết quả ---
                imshow(BW_Thinned, 'Parent', app.UIAxes_Skeleton);

                % Lưu kết quả
                app.Skeleton = BW_Thinned;
                app.temp_var = BW_Thinned;

            catch ME
                uialert(app.UIFigure, "Lỗi trong quá trình skeletonize: " + ME.message, "Lỗi");
            end

            % Đóng hộp thoại tiến trình
            close(d);


        end

        % Button pushed function: Button_show_skeletonization
        function Button_show_skeletonizationPushed(app, event)
            if(isempty(app.ProcessedImage))
                uialert(app.UIFigure, 'Chưa có ảnh được xử lý.', 'Lỗi');
                return;
            else
                figure(4);
                %                 imshow(app.Skeleton);
                overlay = labeloverlay(app.ProcessedImage, app.Skeleton, 'Transparency', 0.5, 'Colormap', [1 0 0]);
                imshow(overlay);

                title("Ảnh Skeleton");

            end
        end

        % Button pushed function: Button_RefineSkeleton
        function Button_RefineSkeletonPushed(app, event)

            if isempty(app.Skeleton)
                uialert(app.UIFigure, 'Chưa có ảnh Skeleton đầu vào.', 'Lỗi');
                return;
            end

            % Hiển thị hộp thoại thông báo đang xử lý
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện refine skeleton ảnh, vui lòng chờ...', ...
                'Indeterminate', 'on');

            try
                %                 % Chuyển ảnh sang nhị phân
                %                 binaryImg = imbinarize(app.Skeleton);

                % Gọi hàm tinh chỉnh skeleton
                refined = app.refineSkeleton(app.Skeleton);

                % Lưu lại skeleton đã refine
                app.Skeleton_refined = refined;

                %
                if isempty(app.Skeleton_refined)
                    app.Button_RefineSkeleton_iter.Enable = 'off'; % Ẩn nút
                    app.Button_manual_remove.Enable = 'off';
                    app.Button_manual_link.Enable = 'off';

                else
                    app.Button_RefineSkeleton_iter.Enable = 'on';  % Hiện nút
                    app.Button_manual_remove.Enable = 'on';
                    app.Button_manual_link.Enable = 'on';
                end


                % Hiển thị ảnh kết quả
                figure(5);
                imshow(refined); title("Ảnh sau khi refine skeleton");


            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi xử lý ảnh:\n%s', ME.message), 'Lỗi');
            end

            % Đóng progress dialog
            close(d);

        end

        % Button pushed function: Button_RefineSkeleton_iter
        function Button_RefineSkeleton_iterPushed(app, event)

            % Hiển thị hộp thoại thông báo đang xử lý
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện refine skeleton ảnh, vui lòng chờ...', ...
                'Indeterminate', 'on');

            try
                %                 % Chuyển ảnh sang nhị phân
                %                 binaryImg = imbinarize(app.Skeleton);

                % Gọi hàm tinh chỉnh skeleton
                refined = app.refineSkeleton(app.Skeleton_refined);

                % Lưu lại skeleton đã refine
                app.Skeleton_refined = refined;

                % Hiển thị ảnh kết quả
                figure(6);
                imshow(refined); title("Ảnh sau khi refine skeleton iter");


            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi xử lý ảnh:\n%s', ME.message), 'Lỗi');
            end
            % lưu giá trị trung gian
            app.temp_var = refined;
            % Đóng progress dialog
            close(d);
        end

        % Button pushed function: Button_manual_remove
        function Button_manual_removePushed(app, event)

          % Lấy giá trị lựa chọn từ dropdown
            value = app.DropDown_manual_remove.Value;

            % Hiển thị ảnh để thao tác
            figure(app.figure_manual_remove);
            hImg = imshow(app.Skeleton_refined);
            axis image off;
            title('Vẽ vùng muốn xóa (nhấn Enter để kết thúc)');
            hold on;

            % Khởi tạo mặt nạ tổng
            maskTotal = false(size(app.Skeleton_refined));

            disp('Vẽ các vùng cần xóa, nhấn Enter để kết thúc.');

            while true
                % Nếu người dùng đóng cửa sổ thì thoát
                if ~ishandle(app.figure_manual_remove)
                    disp('Cửa sổ đã bị đóng, hủy thao tác.');
                    return;
                end

                w = waitforbuttonpress;
                if w == 1  % Nhấn phím
                    key = get(gcf, 'CurrentCharacter');
                    if strcmp(key, char(13))  % Enter
                        break;
                    end
                else
                    switch value
                        case 'Freehand'
                            % Vẽ tự do
                            h = drawfreehand('Color', 'r', 'LineWidth', 1.5);

                        case 'HCN'
                            % Vẽ hình chữ nhật
                            h = drawrectangle('Color', 'b', 'LineWidth', 1.5);

                        otherwise
                            uialert(app.UIFigure, 'Chưa chọn kiểu xóa hợp lệ.', 'Lỗi');
                            return;
                    end

                    % Nếu không có vùng hợp lệ thì bỏ qua
                    if isempty(h) || isempty(h.Position)
                        continue;
                    end

                    % Tạo mặt nạ từ vùng đã vẽ
                    mask = createMask(h, hImg);

                    delete(h);  % Xóa object sau khi lấy mask

                    % Cộng dồn mask
                    maskTotal = maskTotal | mask;

                    % Hiển thị vùng vừa chọn
                    visboundaries(mask, 'Color', 'r');
                end
            end

            % Cập nhật skeleton
            app.Skeleton_refined(maskTotal) = 0;

            % Hiển thị kết quả
            figure(app.figure_manual_remove);
            imshow(app.Skeleton_refined);
            axis image off;
            title('Ảnh sau khi xóa thủ công');

            % luu bien trung gian
            app.temp_var = app.Skeleton_refined;


        end

        % Button pushed function: Button_manual_link
        function Button_manual_linkPushed(app, event)
  
% sửa lần 2 - vẫn chạy oke
%         figure(app.figure_manual_link);
%         imshow(app.ProcessedImage);                % Hiển thị ảnh vân ban đầu
%         hold on;
%         hImg = imshow(app.Skeleton_refined); % Chồng ảnh skeleton
%         set(hImg, 'AlphaData', 1);         % Điều chỉnh độ trong suốt
%         axis image off;
%         title('Vẽ đường nối vân (Enter để kết thúc)');
% 
% 
%             disp('Vẽ đường cần nối bằng tay. Nhấn Enter để kết thúc.');
% 
%             while true
%                 % Nếu người dùng đóng cửa sổ thì thoát
%                 if ~ishandle(app.figure_manual_link)
%                     disp('Cửa sổ đã bị đóng. Hủy thao tác.');
%                     return;
%                 end
% 
%                 % Chờ phím hoặc chuột
%                 w = waitforbuttonpress;
%                 if w == 1  % Người dùng nhấn phím
%                     key = get(gcf, 'CurrentCharacter');
%                     if strcmp(key, char(13))  % Nhấn phím Enter
%                         break;
%                     end
%                 else
%                     % Vẽ đường polyline không khép kín
%                     h = drawpolyline('Color', 'c', 'LineWidth', 2);
% 
%                     if isempty(h.Position)
%                         continue;
%                     end
% 
%                     % Tạo mặt nạ từ đường vẽ (có thể rộng hơn bằng cách dùng imdilate nếu cần)
%                     mask = createMask(h, hImg);  % Truyền handle ảnh để tránh lỗi ambiguity
%                     delete(h);  % Xóa đối tượng để đỡ rối
% 
%                     % Nối vân bằng cách gán giá trị 1 (foreground) vào ảnh skeleton
%                     app.Skeleton_refined(mask) = 1;
% 
%                     % Hiển thị lại đường vừa nối
%                     visboundaries(mask, 'Color', 'g');
%                 end
%             end
% 
%             % Hiển thị kết quả sau khi nối
%             figure(app.figure_manual_link);
%             imshow(app.Skeleton_refined);
%             axis image off;
%             title('Ảnh sau khi nối vân bằng tay');
% 
%             % luu bien trung gian
%             app.temp_var = app.Skeleton_refined;


%sửa lần 3
% Hiển thị ảnh gốc và overlay skeleton dưới dạng màu để dễ quan sát
figure(app.figure_manual_link);
imshow(app.ProcessedImage);               % Hiển thị ảnh gốc
hold on;

% Tô màu đỏ cho ảnh skeleton để overlay
coloredSkeleton = cat(3, app.Skeleton_refined, zeros(size(app.Skeleton_refined)), zeros(size(app.Skeleton_refined))); % R-G-B

% Hiển thị ảnh skeleton overlay với alpha thấp để không che ảnh gốc
hImg = imshow(coloredSkeleton);
set(hImg, 'AlphaData', 0.5);              % Độ trong suốt, dễ quan sát ảnh gốc

axis image off;
title('Vẽ đường nối vân (Enter để kết thúc)');

disp('Vẽ đường cần nối bằng tay. Nhấn Enter để kết thúc.');

while true
    % Nếu cửa sổ đã đóng thì thoát
    if ~ishandle(app.figure_manual_link)
        disp('Cửa sổ đã bị đóng. Hủy thao tác.');
        return;
    end

    w = waitforbuttonpress;
    if w == 1  % Nhấn phím
        key = get(gcf, 'CurrentCharacter');
        if strcmp(key, char(13))  % Nhấn Enter thì thoát vòng lặp
            break;
        end
    else
        % Vẽ đường polyline không khép kín
        h = drawpolyline('Color', 'c', 'LineWidth', 2);

        % Nếu không vẽ gì -> tiếp tục vòng lặp
        if isempty(h.Position)
            continue;
        end

        % Tạo mask vùng được vẽ
        mask = createMask(h, hImg);

        % Xóa đối tượng vẽ sau khi lấy mask
        delete(h);

        % Gán pixel = 1 trong skeleton
        app.Skeleton_refined(mask) = 1;

        %         % Cập nhật hiển thị overlay
        %         imshow(app.ProcessedImage);
        %         hold on;

        %         % Hiển thị lại skeleton overlay màu đỏ
        %         coloredSkeleton = cat(3, app.Skeleton_refined, zeros(size(app.Skeleton_refined)), zeros(size(app.Skeleton_refined)));
        %         hImg = imshow(coloredSkeleton);
        %         set(hImg, 'AlphaData', 0.5);  % Vẫn để trong suốt

        % Hiển thị biên vùng nối
        visboundaries(mask, 'Color', 'g', 'LineWidth', 1.5);

        %         axis image off;
        %         title('Vẽ đường nối vân (Enter để kết thúc)');
    end
end

% Hiển thị ảnh sau khi hoàn tất thao tác
% Hiển thị kết quả sau khi nối
figure(app.figure_manual_link);
imshow(app.Skeleton_refined);
axis image off;
title('Ảnh sau khi nối vân bằng tay');


% Lưu biến trung gian
app.temp_var = app.Skeleton_refined;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close request function: UIFigure
            figs = findall(0, 'Type', 'figure');
            for k = 1:length(figs)
                if figs(k) ~= app.UIFigure
                    try
                        close(figs(k));
                    catch
                        % Bỏ qua nếu không đóng được
                    end
                end
            end

            % Xóa app nếu cần
            delete(app);

        end

        % Value changed function: DropDown_manual_remove
        function DropDown_manual_removeValueChanged(app, event)
            value = app.DropDown_manual_remove.Value;

        end

        % Button pushed function: Button_fringe_order
        function Button_fringe_orderPushed(app, event)

            % Cắt biên ảnh để tránh ảnh hưởng vùng biên
            offset = 1;
            bw_crop = app.temp_var(offset+1:end-offset, offset+1:end-offset);
            [H, W] = size(bw_crop);

            % Tìm các vùng liên thông (vân)
            cc = bwconncomp(bw_crop);
            labeled_matrix = labelmatrix(cc);
            stats = regionprops(cc, 'Centroid', 'BoundingBox');

            % Tìm nhóm gần tâm nhất làm gốc
            centroids = cat(1, stats.Centroid);
            dist = vecnorm(centroids - [W/2, H/2], 2, 2);
            [~, idx_center] = min(dist);

            % Khởi tạo vector nhãn với NaN
            labels = nan(cc.NumObjects, 1);
            labels(idx_center) = 0; % Nhóm gốc đặt nhãn 0

            queue = idx_center; % Hàng đợi để duyệt lan truyền nhãn
            processed_groups = false(cc.NumObjects, 1);
            processed_groups(idx_center) = true;

            while ~isempty(queue)
                current_group = queue(1);
                queue(1) = [];

                current_label = labels(current_group);
                pixels = cc.PixelIdxList{current_group};
                [rows, cols] = ind2sub([H, W], pixels);

                visited_gid = []; % Tránh xét lại nhóm cùng vòng lặp

                for i = 1:length(rows)
                    r = rows(i); c = cols(i);

                    % Lan truyền lên trên theo cột
                    for y = r-1:-1:1
                        gid = labeled_matrix(y, c);
                        if gid > 0 && ~processed_groups(gid) && ~ismember(gid, visited_gid)
                            labels(gid) = current_label + 1; % Nhãn tăng dần lên trên
                            queue(end+1) = gid;
                            processed_groups(gid) = true;
                            visited_gid(end+1) = gid;
                            break;
                        elseif gid > 0 && processed_groups(gid)
                            break;
                        end
                    end

                    % Lan truyền xuống dưới theo cột
                    for y = r+1:H
                        gid = labeled_matrix(y, c);
                        if gid > 0 && ~processed_groups(gid) && ~ismember(gid, visited_gid)
                            labels(gid) = current_label - 1; % Nhãn giảm dần xuống dưới
                            queue(end+1) = gid;
                            processed_groups(gid) = true;
                            visited_gid(end+1) = gid;
                            break;
                        elseif gid > 0 && processed_groups(gid)
                            break;
                        end
                    end
                end
            end

            % Chuẩn hóa nhãn thành số nguyên dương bắt đầu từ 1
            valid_labels = labels(~isnan(labels));
            unique_labels = unique(valid_labels);
            labels_new = nan(size(labels));
            for i = 1:length(unique_labels)
                labels_new(labels == unique_labels(i)) = i;
            end
            labels = labels_new;

            % Hiển thị kết quả với nhãn trên hình
            figure(app.figure_fringe_order); imshow(bw_crop); hold on;
            %            for k = 1:cc.NumObjects
            %                 if ~isnan(labels(k))
            %                     c = stats(k).Centroid;
            %                     text(c(1), c(2), num2str(labels(k)), 'Color', 'yellow', 'FontSize', 9);
            %                 end
            %             end

            image_center = [W/2, H/2]; % Tâm ảnh (x, y)

            for k = 1:cc.NumObjects
                if ~isnan(labels(k))
                    pixels = cc.PixelIdxList{k};
                    [rows, cols] = ind2sub([H, W], pixels);
                    coords = [cols, rows]; % [x, y]

                    % Tính khoảng cách từ tâm ảnh
                    dists = sqrt((coords(:,1) - image_center(1)).^2 + (coords(:,2) - image_center(2)).^2);
                    [~, min_idx] = min(dists);
                    label_pos = coords(min_idx, :);

                    text(label_pos(1), label_pos(2), num2str(labels(k)), 'Color', 'yellow', 'FontSize', 9);
                end
            end


            title('Gán bậc vân');
            hold off;

            % Lưu kết quả về app
            app.temp_var = bw_crop;
            app.fringe_order = cc.NumObjects;
            app.fringe_labels = labels;

        end

        % Button pushed function: Button_reconstruct_surface
        function Button_reconstruct_surfacePushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang dựng lại bề mặt...', ...
                'Message', 'Vui lòng chờ trong giây lát...', ...
                'Indeterminate', 'on');

            try
                % Thông số vật lý
   
                avg_angle = 0;     % góc trung bình (độ)
                fringe_spacing = (app.lambda / 2) / cosd(abs(avg_angle)); % khoảng cách vân

                BW = app.temp_var;
                [H, W] = size(BW);
                cc = bwconncomp(BW);
                labeled_matrix = labelmatrix(cc);

                % Lấy nhãn đã gán từ bước trước
                if isprop(app, 'fringe_labels')
                    labels = app.fringe_labels;
                else
                    labels = (1:cc.NumObjects)'; % fallback
                end

                % Tạo dữ liệu 3D cho từng pixel của từng nhóm vân
                X = []; Y = []; Z = [];
                stats = regionprops(cc, 'PixelIdxList');
                for i = 1:cc.NumObjects
                    if ~isnan(labels(i))
                        pixels = stats(i).PixelIdxList;
                        [y, x] = ind2sub([H, W], pixels);
                        z = ones(size(x)) * labels(i) * fringe_spacing;
                        X = [X; x(:)];
                        Y = [Y; y(:)];
                        Z = [Z; z(:)];
                    end
                end

                % Chuẩn hóa tọa độ vào miền [-1,1] và lọc điểm trong vòng tròn đơn vị
                Xn = 2 * (X - min(X)) / (max(X) - min(X)) - 1;
                Yn = 2 * (Y - min(Y)) / (max(Y) - min(Y)) - 1;
                R = sqrt(Xn.^2 + Yn.^2);
                inside = R <= 1;
                Xn = Xn(inside);
                Yn = Yn(inside);
                Zn = Z(inside);

                % Fit đa thức Zernike
                maxN = 10;
                B = [];
                [nv, mv] = app.zernikeOrders(maxN);
                for i = 1:length(nv)
                    Zm = app.zernfun(nv(i), mv(i), Xn, Yn);
                    B = [B, Zm(:)];
                end
                c = B \ Zn;

                % Loại bỏ piston, tip, tilt
                if strcmpi(app.DropDown_reconstruc_surface.Value, 'Remove')
                    for i = 1:length(c)
                        if (nv(i) == 0 && mv(i) == 0) || (nv(i) == 1 && abs(mv(i)) == 1)
                            c(i) = 0;
                        end
                    end
                end
                % Loại bỏ piston, tip, tilt ------- Invert Remove
                if strcmpi(app.DropDown_reconstruc_surface.Value, 'Invert Remove')
                    for i = 1:length(c)
                        if (nv(i) == 0 && mv(i) == 0) || (nv(i) == 1 && abs(mv(i)) == 1)
                            c(i) = 0;
                        end
                    end
                end

                % Dựng lưới Zernike trên miền [-1,1]x[-1,1]
                [xq, yq] = meshgrid(linspace(-1, 1, W), linspace(-1, 1, H));
                rq = sqrt(xq.^2 + yq.^2);
                mask = rq <= 1;
                Zq = zeros(size(xq));
                for i = 1:length(c)
                    Zq = Zq + c(i) * app.zernfun(nv(i), mv(i), xq, yq);
                end
                Zq(~mask) = NaN;

%                 % Hiển thị kết quả
%                 figure(app.fiugre_reconstruct_surface);
%                 title( '3D Surface Reconstructed by Zernike Fit')
%                 surf(xq, yq, -Zq, 'EdgeColor', 'none');
%                 colormap turbo; colorbar;
%                 xlabel('X (norm)'); ylabel('Y (norm)'); zlabel('Height (m)');
%                 title('3D Surface Reconstructed from Fringes (Zernike fit)');
%                 cbar = colorbar;
%                 cbar.Label.String = 'Height (m)';

                % hiển thị kết quả rad
                phi_rad = (4 * pi / app.lambda) * Zq;  % Chuyển từ mét sang radian
                if strcmpi(app.DropDown_reconstruc_surface.Value, 'Invert Remove')
                               phi_rad = -  phi_rad; 
                end
                figure(app.fiugre_reconstruct_surface);
                surf(xq, yq, phi_rad, 'EdgeColor', 'none');
                colormap turbo; colorbar;
                xlabel('X (norm)'); ylabel('Y (norm)'); zlabel('Rad');
                title('3D Surface Reconstructed from Fringes (Rad)');
                cbar = colorbar;
                cbar.Label.String = 'rad';


                % Lưu kết quả lên app
                app.recons_surface = phi_rad;

            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi khi dựng bề mặt');
            end

            close(d);


        end

        % Button pushed function: Button_remove_tilt
        function Button_remove_tiltPushed(app, event)
            % Button pushed function: Button_remove_tilt
            crop_val = 20;
            Zq = app.recons_surface; % Bề mặt đã dựng bằng Zernike, bạn cần lưu nó vào app.Zq trước đó
            phi_rad = (4 * pi / app.lambda) * Zq;  % Chuyển từ mét sang radian
            Zq = phi_rad;
            % Cắt viền nếu đủ lớn
            if size(Zq,1) > 2*crop_val && size(Zq,2) > 2*crop_val
                Z = Zq(crop_val+1:end-crop_val, crop_val+1:end-crop_val);
            else
                Z = Zq;
            end

            [M, N] = size(Z);
            [xGrid, yGrid] = meshgrid(1:N, 1:M);
            z = Z(:);
            good = ~isnan(z); % Chỉ lấy điểm hợp lệ

            %% Fit mặt phẳng và loại bỏ nghiêng
            A = [xGrid(:), yGrid(:), ones(numel(z),1)];
            coeff = A(good,:) \ z(good);
            Z_fit = reshape(A * coeff, size(Z));
            Z_leveled = Z - Z_fit;

            %% Chuẩn hóa lại Z (bắt đầu từ 0, đảo chiều nếu cần)
            Z_inverted = -Z_leveled;
            Z_offset = Z_inverted - min(Z_inverted(:));
            % đảo ngược giá trị (bằng dropdown)
            if strcmpi(app.DropDown_remove_tilt.Value, 'Invert')
                Z_offset = -Z_offset;
            end

%             % Hiển thị kết quả (độ cao -mét)
%             figure(app.figure_remove_tilt);
%             surf(xGrid, yGrid, Z_offset, 'EdgeColor', 'none');
%             shading interp;
%             title('3D Surface after Tilt Removal (Leveled)');
%             xlabel('X (px)');
%             ylabel('Y (px)');
%             zlabel('Height (m)');
%             colormap parula;
%             colorbar;

            % Hiển thị kết quả (rad)
            figure(app.figure_remove_tilt);
            surf(xGrid, yGrid, Z_offset, 'EdgeColor', 'none');
            shading interp;
            title('3D Surface after Tilt Removal (Leveled)');
            xlabel('X (px)');
            ylabel('Y (px)');
            zlabel('Rad');
            colormap parula;
            colorbar;


        end

        % Value changed function: DropDown_remove_tilt
        function DropDown_remove_tiltValueChanged(app, event)
            value = app.DropDown_remove_tilt.Value;
            
        end

        % Value changed function: DropDown_reconstruc_surface
        function DropDown_reconstruc_surfaceValueChanged(app, event)
            value = app.DropDown_reconstruc_surface.Value;
            
        end

        % Button pushed function: Button_reconSurface_linear
        function Button_reconSurface_linearPushed(app, event)
            % Kiểm tra xem fringe_labels đã có chưa
            if isempty(app.fringe_labels)
                uialert(app.UIFigure, 'Bạn cần gán nhãn vân trước khi nội suy.', 'Cảnh báo');
                return;
            end

            % Lấy ảnh nhị phân đã cắt biên từ biến tạm
            BW = app.temp_var;
            labels = app.fringe_labels;
            cc = bwconncomp(BW);
            L = labelmatrix(cc);

            % Thiết lập khoảng cách giữa các vân (có thể cho phép nhập từ GUI sau)
            khoang_cach_van = app.lambda/2;

            % Khởi tạo các mảng điểm 3D
            num_labels = max(L(:));
            X = []; Y = []; Z = [];

            for i = 1:num_labels
                % Tìm các điểm thuộc vân có nhãn i
                [y, x] = find(L == i);
                if i <= length(labels)
%                     z = ones(size(x)) * (labels(i) - 1) * khoang_cach_van;
                    z = ones(size(x)) * (labels(i)-1) * khoang_cach_van;
                    X = [X; x];
                    Y = [Y; y];
                    Z = [Z; z];
                end
            end

            % Nội suy để tạo mặt 3D mượt
            [xq, yq] = meshgrid(1:size(BW,2), 1:size(BW,1));
            F = scatteredInterpolant(X, Y, Z, 'natural', 'nearest');
            Zq = F(xq, yq);
            Zq(~isfinite(Zq)) = 0;
            phi_rad = (4 * pi / app.lambda) * Zq;  % Chuyển từ mét sang radian
            Zq = phi_rad;
            % Cắt biên để hiển thị tốt hơn
            margin = 1;
            Z_crop = Zq(margin:end-margin, margin:end-margin);
            [M, N] = size(Z_crop);
            [xGrid, yGrid] = meshgrid(1:N, 1:M);
            x = xGrid(:);
            y = yGrid(:);
            z = Z_crop(:);

            % Lấy tùy chọn xử lý từ dropdown
            tilt_option = app.DropDown_recons_linear.Value;

            % Xử lý theo lựa chọn của người dùng
            switch tilt_option
                case 'None'
                    Z_processed = Z_crop;


                case 'Remove tilt'
                    good = ~isnan(z);
                    A = [x, y, ones(size(x))];
                    coeff = A(good,:) \ z(good);
                    Z_fit = reshape(A * coeff, size(Z_crop));
                    Z_processed = Z_crop - Z_fit;

                case 'Invert'
                    Z_processed = max(Z_crop(:)) - Z_crop;

                case 'Remove + Invert'
                    good = ~isnan(z);
                    A = [x, y, ones(size(x))];
                    coeff = A(good,:) \ z(good);
                    Z_fit = reshape(A * coeff, size(Z_crop));
                    Z_leveled = Z_crop - Z_fit;
                    Z_processed = max(Z_leveled(:)) - Z_leveled;

                otherwise
                    Z_processed = Z_crop;
            end

            % Chuẩn hóa bắt đầu từ 0
            Z_offset = Z_processed - min(Z_processed(:));

            % Hiển thị bề mặt 3D
            figure(app.figure_reconstruct_surface_linear);
            surf(xGrid, yGrid, Z_offset);
            shading interp;
            xlabel('X (px)');
            ylabel('Y (px)');
            zlabel('rad');
            title(['3D Surface Linear (Option: ', tilt_option, ')']);
            colormap parula;
            colorbar;

            % Ghi kết quả vào app
            app.recons_surface = Z_offset;


        end

        % Value changed function: DropDown_recons_linear
        function DropDown_recons_linearValueChanged(app, event)
            value = app.DropDown_recons_linear.Value;
            
        end

        % Button pushed function: Button_Debug
        function Button_DebugPushed(app, event)
            disp("Thông tin ảnh Skeleton:");
            disp(size(app.Skeleton_refined));
            disp(class(app.Skeleton_refined));
            disp("Giá trị max/min:");
            disp([min(app.Skeleton_refined(:)), max(app.Skeleton_refined(:))]);

        end

        % Button pushed function: Button_remove_spurs
        function Button_remove_spursPushed(app, event)
                 % Hiển thị hộp thoại thông báo đang xử lý
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Đang xử lý...', ...
                'Message', 'Đang thực hiện xoá râu ria thừa, vui lòng chờ...', ...
                'Indeterminate', 'on');

            try
                %                 % Chuyển ảnh sang nhị phân
                %                 binaryImg = imbinarize(app.Skeleton);

                % Gọi hàm tinh chỉnh skeleton
                refined = app.xoa_ria(app.Skeleton_refined);

                % Lưu lại skeleton đã refine
                app.Skeleton_refined = refined;

                % Hiển thị ảnh kết quả
                figure(6);
                imshow(refined); title("Ảnh sau khi xoá rìa");

            catch ME
                uialert(app.UIFigure, sprintf('Lỗi khi xử lý ảnh:\n%s', ME.message), 'Lỗi');
            end
            % lưu giá trị trung gian
            app.temp_var = refined;
            % Đóng progress dialog
            close(d);
        end

        % Button pushed function: Button_export_var
        function Button_export_varPushed(app, event)
                figs = findall(0, 'Type', 'figure');
                for k = 1:length(figs)
                    if figs(k) ~= app.UIFigure
                        try
                            close(figs(k));
                        catch
                            % Nếu có lỗi khi đóng thì bỏ qua
                        end
                    end
                end
            
            % Danh sách các biến cần kiểm tra
            varNames = {'CroppedImage', 'grayImg', 'ProcessedImage', ...
                'Skeleton', 'Skeleton_refined', ...
                'fringe_order', 'recons_surface', 'fringe_labels'};

            missingVars = {};  % Danh sách biến bị thiếu

            % Kiểm tra từng biến
            for i = 1:length(varNames)
                varValue = app.(varNames{i});
                if isempty(varValue)
                    missingVars{end+1} = varNames{i}; %#ok<AGROW>
                end
            end

            % Nếu có biến thiếu → hiện thông báo
            if ~isempty(missingVars)
                msg = "Thiếu biến: " + strjoin(missingVars, ', ');
                uialert(app.UIFigure, msg, 'Thiếu dữ liệu', 'Icon', 'warning');
                %                 return;

            else

            % Nếu đầy đủ → cho phép script tiếp tục
            disp("Tất cả biến đã sẵn sàng, xuất về script...");
            end
            uiresume(app.UIFigure);

            
        end

        % Button pushed function: Button_linking
        function Button_linkingPushed(app, event)

            % Cắt biên ảnh để tránh vùng biên ảnh hưởng
            offset = 0;
            bw_crop = app.temp_var(offset+1:end-offset, offset+1:end-offset);
            [H, W] = size(bw_crop);

            % Tìm các vùng liên thông (vân)
            cc = bwconncomp(bw_crop);
            labeled_matrix = labelmatrix(cc);
            stats = regionprops(cc, 'Centroid');

            % Gán nhãn ban đầu: mỗi vân 1 nhãn
            numObjects = cc.NumObjects;
            labels = (1:numObjects)';

            % Cài đặt tham số
            n_tail = 7;            % số pixel cuối dùng để tính hướng
            angle_thresh = 15;     % ngưỡng sai lệch hướng (độ)
            dist_thresh = 30;      % ngưỡng khoảng cách điểm cuối (pixel)

            directions = nan(numObjects, 2);  % vector chỉ phương [dx, dy]
            endpoints = nan(numObjects, 2);   % điểm cuối [x, y]

            % Tính hướng và điểm cuối của từng vân
            for k = 1:numObjects
                pixels = cc.PixelIdxList{k};
                [rows, cols] = ind2sub([H, W], pixels);
                coords = [cols, rows]; % [x, y]

                if size(coords,1) < n_tail
                    continue;
                end

                % Lấy n_tail điểm cuối
                coords_tail = coords(end-n_tail+1:end, :);
                x = coords_tail(:,1);
                y = coords_tail(:,2);

                % Hồi quy tuyến tính: y = ax + b
                p = polyfit(x, y, 1);  % hướng tuyến tính

                % Vector chỉ phương: (1, a)
                dir_vec = [1, p(1)];
                dir_vec = dir_vec / norm(dir_vec);  % chuẩn hóa
                directions(k, :) = dir_vec;

                endpoints(k, :) = coords_tail(end, :); % lấy điểm cuối
            end

            % Gộp các vân dựa trên hướng và khoảng cách điểm cuối
            for i = 1:numObjects
                for j = i+1:numObjects
                    if any(isnan([directions(i,:), directions(j,:), endpoints(i,:), endpoints(j,:)]))
                        continue;
                    end

                    % Góc giữa 2 vector
                    angle_diff = acosd(dot(directions(i,:), directions(j,:)));

                    % Khoảng cách giữa 2 điểm cuối
                    dist = norm(endpoints(i,:) - endpoints(j,:));

                    if angle_diff < angle_thresh && dist < dist_thresh
                        % Gộp nhãn: tất cả gán về nhãn nhỏ hơn
                        min_label = min(labels(i), labels(j));
                        max_label = max(labels(i), labels(j));
                        labels(labels == max_label) = min_label;
                    end
                end
            end

            % Chuẩn hóa nhãn về số nguyên dương liên tục
            unique_labels = unique(labels);
            labels_new = zeros(size(labels));
            for i = 1:length(unique_labels)
                labels_new(labels == unique_labels(i)) = i;
            end
            labels = labels_new;

            % Hiển thị kết quả
            figure(app.figure_fringe_order); imshow(bw_crop); hold on;

            for k = 1:numObjects
                if ~isnan(labels(k))
                    pixels = cc.PixelIdxList{k};
                    [rows, cols] = ind2sub([H, W], pixels);
                    coords = [cols, rows];  % [x, y]

                    % Tìm điểm gần tâm ảnh để đặt label
                    image_center = [W/2, H/2];
                    dists = sqrt((coords(:,1) - image_center(1)).^2 + (coords(:,2) - image_center(2)).^2);
                    [~, min_idx] = min(dists);
                    label_pos = coords(min_idx, :);

                    text(label_pos(1), label_pos(2), num2str(labels(k)), 'Color', 'yellow', 'FontSize', 9);
                end
            end

            title('Gán bậc vân (hướng & điểm cuối)');
            hold off;

            % Lưu vào app
            app.temp_var = bw_crop;
            app.fringe_order = numObjects;
            app.fringe_labels = labels;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {596, 596, 596};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {596, 596};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {237, '1x', 207};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1 ';
            app.Sourcegithubcomtuanpd205459Label = uilabel(app.Tab1);
            app.Sourcegithubcomtuanpd205459Label.Text = 'Source: github.com/tuanpd205459';
            app.FileEditFieldLabel = uilabel(app.Tab1);
            app.FileEditFieldLabel.Text = 'File';
            app.EditField_file_path = uieditfield(app.Tab1, 'text');
            app.Button_add_Img = uibutton(app.Tab1, 'push');
            app.Button_add_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_add_ImgPushed, true);
            app.Button_add_Img.Text = 'Chọn ảnh';
            app.nhchnLabel = uilabel(app.Tab1);
            app.nhchnLabel.Text = 'Ảnh đã chọn:';
            app.Label_image_name = uilabel(app.Tab1);
            app.Label_image_name.Text = 'Tên ảnh';
            app.Button_crop_Img = uibutton(app.Tab1, 'push');
            app.Button_crop_Img.ButtonPushedFcn = createCallbackFcn(app, @Button_crop_ImgPushed, true);
            app.Button_crop_Img.Text = 'Crop ảnh';
            app.Label_func_filter = uilabel(app.Tab1);
            app.Label_func_filter.Text = 'Label_func_filter';
            app.Label_func_nen = uilabel(app.Tab1);
            app.Label_func_nen.Text = 'Label_func_nen';
            app.Label_func_constrast = uilabel(app.Tab1);
            app.Label_func_constrast.Text = 'Label_func_constrast';
            app.LcnhiuLabel = uilabel(app.Tab1);
            app.LcnhiuLabel.Text = 'Lọc nhiễu';
            app.DropDown_FilterMethod = uidropdown(app.Tab1);
            app.DropDown_FilterMethod.Items = {'Không', 'Gaussian', 'Median', 'Wiener', 'All'};
            app.DropDown_FilterMethod.Value = 'Không';
            app.ChunhannLabel = uilabel(app.Tab1);
            app.ChunhannLabel.Text = 'Chuẩn hóa nền';
            app.DropDown_BackgroundNorm = uidropdown(app.Tab1);
            app.DropDown_BackgroundNorm.Items = {'Không', 'imadjust', 'histeq', 'adapthisteq'};
            app.DropDown_BackgroundNorm.Value = 'Không';
            app.TngphnLabel = uilabel(app.Tab1);
            app.TngphnLabel.Text = 'Tương phản';
            app.DropDown_ContrastEnhance = uidropdown(app.Tab1);
            app.DropDown_ContrastEnhance.Items = {'Không', 'GaussianSubtract'};
            app.DropDown_ContrastEnhance.Value = 'Không';
            app.Button_Enhance = uibutton(app.Tab1, 'push');
            app.Button_Enhance.ButtonPushedFcn = createCallbackFcn(app, @Button_EnhancePushed, true);
            app.Button_Enhance.Text = 'Xử lý ảnh';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Button_linking = uibutton(app.Tab2, 'push');
            app.Button_linking.ButtonPushedFcn = createCallbackFcn(app, @Button_linkingPushed, true);
            app.Button_linking.Text = 'Nối vân';
            app.Button_Debug = uibutton(app.LeftPanel, 'push');
            app.Button_Debug.ButtonPushedFcn = createCallbackFcn(app, @Button_DebugPushed, true);
            app.Button_Debug.Text = 'Debug';
            app.Button_export_var = uibutton(app.LeftPanel, 'push');
            app.Button_export_var.ButtonPushedFcn = createCallbackFcn(app, @Button_export_varPushed, true);
            app.Button_export_var.Text = 'Export 2 Script';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes_display_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_display_Img, 'Ảnh ban đầu (gray scale)')
            zlabel(app.UIAxes_display_Img, 'Z')
            app.UIAxes_display_Img.XTick = [];
            app.UIAxes_display_Img.YTick = [];
            app.UIAxes_crop_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_crop_Img, 'Ảnh cropped')
            zlabel(app.UIAxes_crop_Img, 'Z')
            app.UIAxes_crop_Img.XTick = [];
            app.UIAxes_crop_Img.YTick = [];
            app.UIAxes_enhance_Img = uiaxes(app.GridLayout2);
            title(app.UIAxes_enhance_Img, 'Ảnh sau xử lý')
            zlabel(app.UIAxes_enhance_Img, 'Z')
            app.UIAxes_enhance_Img.XTick = [];
            app.UIAxes_enhance_Img.YTick = [];
            app.UIAxes_Skeleton = uiaxes(app.GridLayout2);
            title(app.UIAxes_Skeleton, 'Skeleton ')
            zlabel(app.UIAxes_Skeleton, 'Z')
            app.UIAxes_Skeleton.XTick = [];
            app.UIAxes_Skeleton.YTick = [];
            app.Button_show_img_orig = uibutton(app.GridLayout2, 'push');
            app.Button_show_img_orig.ButtonPushedFcn = createCallbackFcn(app, @Button_show_img_origPushed, true);
            app.Button_show_img_orig.Text = 'Show ảnh';
            app.Button_show_cropped = uibutton(app.GridLayout2, 'push');
            app.Button_show_cropped.ButtonPushedFcn = createCallbackFcn(app, @Button_show_croppedPushed, true);
            app.Button_show_cropped.Text = 'Show ảnh';
            app.Button_show_enhance = uibutton(app.GridLayout2, 'push');
            app.Button_show_enhance.ButtonPushedFcn = createCallbackFcn(app, @Button_show_enhancePushed, true);
            app.Button_show_enhance.Text = 'Show ảnh';
            app.Button_show_skeletonization = uibutton(app.GridLayout2, 'push');
            app.Button_show_skeletonization.ButtonPushedFcn = createCallbackFcn(app, @Button_show_skeletonizationPushed, true);
            app.Button_show_skeletonization.Text = 'Show ảnh';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Button_skeletonize = uibutton(app.GridLayout3, 'push');
            app.Button_skeletonize.ButtonPushedFcn = createCallbackFcn(app, @Button_skeletonizePushed, true);
            app.Button_skeletonize.Text = 'Skeletonize';
            app.Button_RefineSkeleton = uibutton(app.GridLayout3, 'push');
            app.Button_RefineSkeleton.ButtonPushedFcn = createCallbackFcn(app, @Button_RefineSkeletonPushed, true);
            app.Button_RefineSkeleton.Text = 'Refine Skeleton';
            app.Button_RefineSkeleton_iter = uibutton(app.GridLayout3, 'push');
            app.Button_RefineSkeleton_iter.ButtonPushedFcn = createCallbackFcn(app, @Button_RefineSkeleton_iterPushed, true);
            app.Button_RefineSkeleton_iter.Text = 'Iter Refine';
            app.Button_manual_remove = uibutton(app.GridLayout3, 'push');
            app.Button_manual_remove.ButtonPushedFcn = createCallbackFcn(app, @Button_manual_removePushed, true);
            app.Button_manual_remove.Text = 'Manual remove';
            app.Button_manual_link = uibutton(app.GridLayout3, 'push');
            app.Button_manual_link.ButtonPushedFcn = createCallbackFcn(app, @Button_manual_linkPushed, true);
            app.Button_manual_link.Text = 'Manual Link';
            app.DropDown_manual_remove = uidropdown(app.GridLayout3);
            app.DropDown_manual_remove.Items = {'Free hand', 'HCN'};
            app.DropDown_manual_remove.ValueChangedFcn = createCallbackFcn(app, @DropDown_manual_removeValueChanged, true);
            app.DropDown_manual_remove.Value = 'Free hand';
            app.Button_fringe_order = uibutton(app.GridLayout3, 'push');
            app.Button_fringe_order.ButtonPushedFcn = createCallbackFcn(app, @Button_fringe_orderPushed, true);
            app.Button_fringe_order.Text = 'Fringe Order';
            app.Button_reconstruct_surface = uibutton(app.GridLayout3, 'push');
            app.Button_reconstruct_surface.ButtonPushedFcn = createCallbackFcn(app, @Button_reconstruct_surfacePushed, true);
            app.Button_reconstruct_surface.Text = 'Reconstruct Surface';
            app.Button_remove_tilt = uibutton(app.GridLayout3, 'push');
            app.Button_remove_tilt.ButtonPushedFcn = createCallbackFcn(app, @Button_remove_tiltPushed, true);
            app.Button_remove_tilt.Text = 'Remove tilt';
            app.DropDown_remove_tilt = uidropdown(app.GridLayout3);
            app.DropDown_remove_tilt.Items = {'None', 'Invert'};
            app.DropDown_remove_tilt.ValueChangedFcn = createCallbackFcn(app, @DropDown_remove_tiltValueChanged, true);
            app.DropDown_remove_tilt.Value = 'None';
            app.DropDown_reconstruc_surface = uidropdown(app.GridLayout3);
            app.DropDown_reconstruc_surface.Items = {'None', 'Remove', 'Invert', 'Invert Remove'};
            app.DropDown_reconstruc_surface.ValueChangedFcn = createCallbackFcn(app, @DropDown_reconstruc_surfaceValueChanged, true);
            app.DropDown_reconstruc_surface.Value = 'None';
            app.Button_reconSurface_linear = uibutton(app.GridLayout3, 'push');
            app.Button_reconSurface_linear.ButtonPushedFcn = createCallbackFcn(app, @Button_reconSurface_linearPushed, true);
            app.Button_reconSurface_linear.Text = 'Reconstruct Surface Linear';
            app.DropDown_recons_linear = uidropdown(app.GridLayout3);
            app.DropDown_recons_linear.Items = {'None', 'Invert', 'Remove tilt', 'Remove + Invert'};
            app.DropDown_recons_linear.ValueChangedFcn = createCallbackFcn(app, @DropDown_recons_linearValueChanged, true);
            app.DropDown_recons_linear.Value = 'None';
            app.Button_remove_spurs = uibutton(app.GridLayout3, 'push');
            app.Button_remove_spurs.ButtonPushedFcn = createCallbackFcn(app, @Button_remove_spursPushed, true);
            app.Button_remove_spurs.Text = 'Xoa rìa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_fringe_detection_backup4_6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end