classdef noi_van_bang_tay_4925 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        export_codeButton   matlab.ui.control.Button
        LoadanhgocButton    matlab.ui.control.Button
        LoadskeletonButton  matlab.ui.control.Button
        ManuallinkButton    matlab.ui.control.Button
        UIAxes_anh_goc      matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        SkeletonImage ;
        anhgoc;
        figure_manual_link =1 ;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ManuallinkButton
        function ManuallinkButtonPushed(app, event)
            endpoints = bwmorph(app.SkeletonImage,'endpoints');
            [y,x] = find(endpoints);   % toạ độ các endpoint

            %sửa lần 3
            figure;
            imshow(app.anhgoc);               % ảnh gốc
            hold on;

            % overlay skeleton màu đỏ
            redMask = cat(3, app.SkeletonImage, zeros(size(app.SkeletonImage)), zeros(size(app.SkeletonImage)));
            hImg = imshow(redMask);
            set(hImg, 'AlphaData', app.SkeletonImage*0.7);   % trong suốt 70%

            % đánh dấu endpoint bằng chấm xanh/đỏ
            plot(x, y, 'go', 'MarkerSize', 6, 'LineWidth', 1.5);   % 'go' = green circle

            axis image off;
            title('Vẽ đường nối vân (Enter để kết thúc)');

            disp('Vẽ đường cần nối bằng tay. Nhấn Enter để kết thúc.');

            while true
                % Nếu cửa sổ đã đóng thì thoát
                if ~ishandle(app.figure_manual_link)
                    disp('Cửa sổ đã bị đóng. Hủy thao tác.');
                    return;
                end

                w = waitforbuttonpress;
                if w == 1  % Nhấn phím
                    key = get(gcf, 'CurrentCharacter');
                    if strcmp(key, char(13))  % Nhấn Enter thì thoát vòng lặp
                        break;
                    end
                else
                    % Vẽ đường polyline không khép kín
                    h = drawpolyline('Color', 'c', 'LineWidth', 2);

                    % Nếu không vẽ gì -> tiếp tục vòng lặp
                    if isempty(h.Position)
                        continue;
                    end

                    % Tạo mask vùng được vẽ
                    mask = createMask(h, hImg);

                    % Xóa đối tượng vẽ sau khi lấy mask
                    delete(h);

                    % Gán pixel = 1 trong skeleton
                    app.SkeletonImage(mask) = 1;

                    %         % Cập nhật hiển thị overlay
                    %         imshow(app.ProcessedImage);
                    %         hold on;

                    %         % Hiển thị lại skeleton overlay màu đỏ
                    %         coloredSkeleton = cat(3, app.Skeleton_refined, zeros(size(app.Skeleton_refined)), zeros(size(app.Skeleton_refined)));
                    %         hImg = imshow(coloredSkeleton);
                    %         set(hImg, 'AlphaData', 0.5);  % Vẫn để trong suốt

                    % Hiển thị biên vùng nối
                    visboundaries(mask, 'Color', 'g', 'LineWidth', 1.5);

                    %         axis image off;
                    %         title('Vẽ đường nối vân (Enter để kết thúc)');
                end
            end

            % Hiển thị ảnh sau khi hoàn tất thao tác
            % Hiển thị kết quả sau khi nối
            figure(app.figure_manual_link);
            imshow(app.SkeletonImage);
            axis image off;
            title('Ảnh sau khi nối vân bằng tay');


        end

        % Button pushed function: LoadskeletonButton
        function LoadskeletonButtonPushed(app, event)
            % --- Button pushed function: LoadButton
            [file, path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp','Image Files'}, 'Select an image');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                img = imread(fullfile(path,file));    % đọc ảnh
                % Nếu ảnh xám hoặc nhị phân thì giữ nguyên
                % Nếu RGB thì chuyển sang grayscale
                if size(img,3) == 3
                    img = rgb2gray(img);
                end

                % Hiển thị trong UIAxes
                imshow(img,'Parent',app.UIAxes);

                % Lưu vào property của app để dùng tiếp
                app.SkeletonImage = img;
            end

        end

        % Button pushed function: LoadanhgocButton
        function LoadanhgocButtonPushed(app, event)
            % --- Button pushed function: LoadButton
            [file, path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp','Image Files'}, 'Select an image');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                img = imread(fullfile(path,file));    % đọc ảnh
                % Nếu ảnh xám hoặc nhị phân thì giữ nguyên
                % Nếu RGB thì chuyển sang grayscale
                if size(img,3) == 3
                    img = rgb2gray(img);
                end

                % Hiển thị trong UIAxes
                imshow(img,'Parent',app.UIAxes_anh_goc);

                % Lưu vào property của app để dùng tiếp
                app.anhgoc = img;
            end
        end

        % Button pushed function: export_codeButton
        function export_codeButtonPushed(app, event)
            % Button pushed function: ExportButton
            assignin('base','SkeletonImage',app.SkeletonImage); % xuất ra workspace (tùy bạn)
            disp('Skeleton exported!');

            uiresume(app.UIFigure);   % cho phép script chính tiếp tục chạy

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_anh_goc = uiaxes(app.UIFigure);
            title(app.UIAxes_anh_goc, 'Title')
            xlabel(app.UIAxes_anh_goc, 'X')
            ylabel(app.UIAxes_anh_goc, 'Y')
            zlabel(app.UIAxes_anh_goc, 'Z')
            app.ManuallinkButton = uibutton(app.UIFigure, 'push');
            app.ManuallinkButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallinkButtonPushed, true);
            app.ManuallinkButton.Text = 'Manual link';
            app.LoadskeletonButton = uibutton(app.UIFigure, 'push');
            app.LoadskeletonButton.ButtonPushedFcn = createCallbackFcn(app, @LoadskeletonButtonPushed, true);
            app.LoadskeletonButton.Text = 'Load skeleton';
            app.LoadanhgocButton = uibutton(app.UIFigure, 'push');
            app.LoadanhgocButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanhgocButtonPushed, true);
            app.LoadanhgocButton.Text = 'Load anh goc';
            app.export_codeButton = uibutton(app.UIFigure, 'push');
            app.export_codeButton.ButtonPushedFcn = createCallbackFcn(app, @export_codeButtonPushed, true);
            app.export_codeButton.Text = 'export_code';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noi_van_bang_tay_4925

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end